classdef Biomarkers < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BioFace3DUIFigure       matlab.ui.Figure
        SessionMenu             matlab.ui.container.Menu
        NewsessionMenu          matlab.ui.container.Menu
        SettingsMenu            matlab.ui.container.Menu
        ExitMenu                matlab.ui.container.Menu
        Menu_3                  matlab.ui.container.Menu
        Toolbar                 matlab.ui.container.Toolbar
        vsLabel                 matlab.ui.control.Label
        LabelG2Name             matlab.ui.control.Label
        filesNumberLabel_2      matlab.ui.control.Label
        LabelG1Name             matlab.ui.control.Label
        SettingsButton          matlab.ui.control.Button
        filesNumberLabel        matlab.ui.control.Label
        dataSessionLabel        matlab.ui.control.Label
        SessionNameLabel        matlab.ui.control.Label
        StatusLabel             matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        TabGroup                matlab.ui.container.TabGroup
        ProcrustesGPATab        matlab.ui.container.Tab
        ImageLogo_2             matlab.ui.control.Image
        Image_2                 matlab.ui.control.Image
        PanelSave               matlab.ui.container.Panel
        SaveExcelButton         matlab.ui.control.Button
        SavefigureButton        matlab.ui.control.Button
        PanelPlot_2             matlab.ui.container.Panel
        MorphospaceCheckBox     matlab.ui.control.CheckBox
        LegendCheckBox_2        matlab.ui.control.CheckBox
        FilenamesCheckBox       matlab.ui.control.CheckBox
        Panel_2                 matlab.ui.container.Panel
        CentroidsizenormalizationCheckBox  matlab.ui.control.CheckBox
        ComputeButton           matlab.ui.control.Button
        GeneralizedProcrustesAnalysisLabel  matlab.ui.control.Label
        ProcrustesUIAxes        matlab.ui.control.UIAxes
        EDMATab                 matlab.ui.container.Tab
        PanelSave_2             matlab.ui.container.Panel
        RelativeCheckBox        matlab.ui.control.CheckBox
        AbsoluteCheckBox        matlab.ui.control.CheckBox
        NodiffCheckBox          matlab.ui.control.CheckBox
        DiffCheckBox            matlab.ui.control.CheckBox
        SaveExcelButton_2       matlab.ui.control.Button
        SavefiguresButton       matlab.ui.control.Button
        ImageLogo               matlab.ui.control.Image
        Image                   matlab.ui.control.Image
        PanelPlot               matlab.ui.container.Panel
        LegendCheckBox          matlab.ui.control.CheckBox
        PercentagesCheckBox     matlab.ui.control.CheckBox
        LandmarksCheckBox       matlab.ui.control.CheckBox
        EDMA_numSLDIst          matlab.ui.control.Spinner
        ShorterLongerDistancesSpinnerLabel  matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        ComputeButton_2         matlab.ui.control.Button
        IterationsSpinner       matlab.ui.control.Spinner
        IterationsSpinnerLabel  matlab.ui.control.Label
        AlphaSpinner            matlab.ui.control.Spinner
        AlphaSpinnerLabel       matlab.ui.control.Label
        PlotButtonGroup         matlab.ui.container.ButtonGroup
        ShowdifferencesButton   matlab.ui.control.RadioButton
        ShowdistancesButton     matlab.ui.control.RadioButton
        EuclideanDistanceMatrixAnalysisLabel  matlab.ui.control.Label
        EDMAUIAxes2             matlab.ui.control.UIAxes
        EDMAUIAxes1             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        path_session
        %variables
        session_name
        output_path
        %Excel
        X
        Y
        Z
        files
        ID_groups
        valid_data
        g1_name
        g1_file
        g2_name
        g2_file
        model_land
        id_diff_excel
        diff_excel
        diff_rel_excel
        %GPA
        template_land_x
        template_land_y
        pc_pca2
        pc_pca
        pca_latent
        procrustes_coord
        %EDMA
        EDMA_FDM_1vs2_boot
        EDMA_CI_idx
        EDMA_h_1vs2
        EDMA_id_ij
        EDMA_mean_dist_1
    end
    
    properties (Access = public)
        settings_gui
        bioface_gui
    end
    
    methods (Access = private)
        % Read dara JSON
        function readDataSession(app, path)
            try
                % Read JSON from the file
                jsonStr = fileread(path);
    
                % Convert JSON to a MATLAB structure
                jsonData = jsondecode(jsonStr);
    
                % Access the values from the structure
                app.session_name = jsonData.session.name;
                app.output_path = jsonData.session.output_path;
                app.model_land = jsonData.biomarkers.num_landmarks;
                app.g1_name = jsonData.biomarkers.group1_name;
                app.g2_name = jsonData.biomarkers.group2_name;
                app.g1_file = jsonData.biomarkers.group1_file;
                app.g2_file = jsonData.biomarkers.group2_file;

                % Display values in text labels (replace 'LabelName', 'LabelInputPath', 'LabelOutputPath' with actual labels)
                app.SessionNameLabel.Text = ['Session: ' app.session_name];
                app.LabelG1Name.Text = app.g1_name;
                app.LabelG2Name.Text = app.g2_name;
    
            catch
                uialert(app.BioFace3DUIFigure, 'Error reading or displaying session values', 'Error', 'Icon', 'error');
            end
        end
     
        % Load data from Excels
        function [X, Y, Z, files, ID_groups] = readExcelAndExtractLandmarks(app, file_group1, file_group2)
            app.TextArea.Value = strcat("   >> Charging Excel files.");
            drawnow;

            % Read group 1
            [X1, Y1, Z1, files1] = extractDataFile(app,file_group1);
            % Read group 2
            [X2, Y2, Z2, files2] = extractDataFile(app,file_group2);

            if isempty(X1) || isempty (X2)
                X = [];
                Y = [];
                Z = [];
                files = [];
                ID_groups = [];
                app.valid_data = false;
                app.TextArea.Value = strcat("   >>");
                drawnow;
                return;
            end
        
            X = [X1(:,:); X2(:,:)];
            Y = [Y1(:,:); Y2(:,:)];
            Z = [Z1(:,:); Z2(:,:)];
            files = [files1; files2];
            ID_groups = [zeros(size(files1,1),1); ones(size(files2,1),1)];
            app.valid_data = true;
            app.TextArea.Value = strcat("   >>");
            drawnow;
        end

        function [X, Y, Z, files] = extractDataFile(app,filePath)
            app.TextArea.Value = strcat("   >> Extracting data from file.");
            drawnow;
            
            X = [];
            Y = [];
            Z = [];
            files = [];

            try 
                % Read the Excel file
                [land, ~, rawData] = xlsread(filePath);
                
                 % Find the column with the header "file"
                fileColumnIndex = find(strcmp(rawData(1, :), 'file'));
            
                if isempty(fileColumnIndex)
                    uialert(app.BioFace3DUIFigure, 'Problem opening excel files. Invalid format. Change file.', 'Error', 'Icon', 'error');
                    app.TextArea.Value = strcat("   >>");
                    drawnow;
                    return;
                end
            
                % Get the files from the "file" column
                files = rawData(2:end, fileColumnIndex);
            
                % Get the landmark coordinates
                landmarkData = land;
            
                % Calculate the number of landmarks (each landmark has three coordinates)
                numLandmarks = size(landmarkData, 2) / 3;
            
                if numLandmarks < 1
                    uialert(app.BioFace3DUIFigure, 'Problem opening excel files. Invalid format. Change file.', 'Error', 'Icon', 'error');
                    app.TextArea.Value = strcat("   >>");
                    drawnow;
                    return;
                end
            
                % Initialize variables X, Y, Z
                X = zeros(size(landmarkData, 1), numLandmarks);
                Y = zeros(size(landmarkData, 1), numLandmarks);
                Z = zeros(size(landmarkData, 1), numLandmarks);
            
                % Extract landmark coordinates
                for i = 1:numLandmarks
                    colIndex = (i - 1) * 3 + 1;
                    X(:, i) = landmarkData(:, colIndex);
                    Y(:, i) = landmarkData(:, colIndex + 1);
                    Z(:, i) = landmarkData(:, colIndex + 2);
                end
    
                app.TextArea.Value = strcat("   >>");
                drawnow;

            catch
               uialert(app.BioFace3DUIFigure, 'Error reading Excel file(s).', 'Error', 'Icon', 'error');
               app.TextArea.Value = strcat("   >>");
               drawnow;
            end
        end

        %% GPA

        % Scale landmarks to centroid size
        function [pc_x, pc_y, pc_z] = scaling(app, x, y, z)
            app.TextArea.Value = strcat("   >> Scaling and translating data.");
            drawnow;

            num_point = size(x,2);
            
            % Compute centroid size
            C = [mean(x,2), mean(y,2), mean(z,2)];
            CS = sum(((x-repmat(C(:,1),1,num_point)).^2+(y-repmat(C(:,2),1,num_point)).^2+(z-repmat(C(:,3),1,num_point)).^2),2).^0.5;
            
            pc_x = (x-repmat(C(:,1),1,num_point))./repmat(CS,1,num_point);
            pc_y = (y-repmat(C(:,2),1,num_point))./repmat(CS,1,num_point);
            pc_z = (z-repmat(C(:,3),1,num_point))./repmat(CS,1,num_point);

            app.TextArea.Value = strcat("   >>");
            drawnow;
        end
        
        % Rotate landmarks to common orientation
        function [pc_x_r, pc_y_r, pc_z_r] = rotation(app, pc_x, pc_y, pc_z)
            app.TextArea.Value = strcat("   >> Rotating data.");
            drawnow;

            num_point = size(pc_x,2);
            num_samples = size(pc_x,1);
            a = (-5:1:5)*(pi/180);
        
            pc_x_r = zeros(num_samples,num_point,length(a),length(a),length(a));
            pc_y_r = zeros(num_samples,num_point,length(a),length(a),length(a));
            pc_z_r = zeros(num_samples,num_point,length(a),length(a),length(a));
                
            for a1=1:length(a)
                R1=[cos(a(a1)) -sin(a(a1)) 0;sin(a(a1)) cos(a(a1)) 0; 0 0 1];
                for a2=1:length(a)
                    R2=[cos(a(a2)) 0 sin(a(a2));0 1 0; -sin(a(a2)) 0 cos(a(a2))];
                    for a3=1:length(a)
                        R3=[1 0 0; 0 cos(a(a3)) -sin(a(a3)); 0 sin(a(a3)) cos(a(a3))];
                        for p=1:num_samples 
                            pcr = R1*R2*R3*[pc_x(p,:) ; pc_y(p,:) ; pc_z(p,:)];
                            pc_x_r(p,:,a1,a2,a3) = pcr(1,:);
                            pc_y_r(p,:,a1,a2,a3) = pcr(2,:); 
                            pc_z_r(p,:,a1,a2,a3) = pcr(3,:); 
                        end
                    end
                end
            end

            app.TextArea.Value = strcat("   >>");
            drawnow;
        end
        
        % Procrustes
        function PC_T = procrustes(app, pc_x,pc_y,pc_z,pc_x_r,pc_y_r,pc_z_r)
            app.TextArea.Value = strcat("   >> Procrustes analysis");
            drawnow;
        
            num_point   = size(pc_x,2);
            num_samples = size(pc_x,1);
            num_angles  = size(pc_x_r,3);
        
            mean_shape      = [pc_x(1,:)', pc_y(1,:)', pc_z(1,:)'];
            mean_shape_pc   = [pc_x(1,:)', pc_y(1,:)', pc_z(1,:)'];
            diff=[];
            for j = 1:10
                dist = squeeze(sum(((repmat(mean_shape_pc(:,1)',num_samples,1,num_angles,num_angles,num_angles)-pc_x_r).^2 + (repmat(mean_shape_pc(:,2)',num_samples,1,num_angles,num_angles,num_angles)-pc_y_r).^2 + (repmat(mean_shape_pc(:,3)',num_samples,1,num_angles,num_angles,num_angles)-pc_z_r).^2).^0.5,2)./num_point);
                [M, id_a1]  = min(dist,[],2);
                id_a1       = squeeze(id_a1);
                M           = squeeze(M);
                [M, id_a2]  = min(M,[],2);
                M           = squeeze(M);
                id_a2       = squeeze(id_a2);
                [~, id_a3]  = min(M,[],2);
                
                for i=1:num_samples
                    pc_x(i,:) = pc_x_r(i,:,  id_a1(i,id_a2(i,id_a3(i)),id_a3(i)),  id_a2(i,id_a3(i)),  id_a3(i));
                    pc_y(i,:) = pc_y_r(i,:,  id_a1(i,id_a2(i,id_a3(i)),id_a3(i)),  id_a2(i,id_a3(i)),  id_a3(i));
                    pc_z(i,:) = pc_z_r(i,:,  id_a1(i,id_a2(i,id_a3(i)),id_a3(i)),  id_a2(i,id_a3(i)),  id_a3(i));
            %       scatter3(pc_x(i,:),pc_y(i,:),pc_z(i,:))
                end
            
                diff(end+1) = sum(sum((mean_shape - [mean(pc_x)', mean(pc_y)', mean(pc_z)']).^2,2).^0.5);
                mean_shape = [mean(pc_x)', mean(pc_y)', mean(pc_z)'];
                mean_shape_C = mean(mean_shape); 
                mean_shape_CS = sum(((mean_shape(:,1)-mean_shape_C(1)).^2+(mean_shape(:,2)-mean_shape_C(2)).^2+(mean_shape(:,3)-mean_shape_C(3)).^2).^0.5);
                mean_shape_pc = (mean_shape - repmat(mean_shape_C,num_point,1))./mean_shape_CS;
            
             end
        
            x_m = [mean_shape(:,1) ; mean_shape(:,2) ; mean_shape(:,3)];
            app.procrustes_coord = [pc_x, pc_y, pc_z];
            PC_T = app.procrustes_coord .* repmat(repmat(sum(x_m.^2),num_samples,1)./(app.procrustes_coord*x_m),1,num_point*3);

            app.TextArea.Value = strcat("   >>");
            drawnow;
        end
        
        % Plot PCA
        function updatePCA(app, pc_pca, SampleNames, IDX, pca_latent)
            app.TextArea.Value = strcat("   >> Plotting Procrustes results");
            drawnow;

            cla(app.ProcrustesUIAxes)
            legend(app.ProcrustesUIAxes,'off')

            % Plot modified data 
            pca2_g1=pc_pca(IDX==0,:);
            pca2_g2=pc_pca(IDX==1,:);
            hold(app.ProcrustesUIAxes, 'on')
            plot(app.ProcrustesUIAxes, pca2_g1(1,1),pca2_g1(1,2), 'bs', 'LineWidth', 1, 'MarkerSize', 5, 'MarkerFaceColor', 'b');
            plot(app.ProcrustesUIAxes, pca2_g2(1,1),pca2_g2(1,2), 'rs', 'LineWidth', 1, 'MarkerSize', 5, 'MarkerFaceColor', 'r');
            plot(app.ProcrustesUIAxes, pca2_g1(2:end,1),pca2_g1(2:end,2), 'bs', 'LineWidth', 1, 'MarkerSize', 5, 'MarkerFaceColor', 'b');
            plot(app.ProcrustesUIAxes, pca2_g2(2:end,1),pca2_g2(2:end,2), 'rs', 'LineWidth', 1, 'MarkerSize', 5, 'MarkerFaceColor', 'r');
            pca_latent = pca_latent/sum(pca_latent);
            xlabel(app.ProcrustesUIAxes, ['PC1(' num2str(pca_latent(1)*100,4) '%)'])
            ylabel(app.ProcrustesUIAxes, ['PC2(' num2str(pca_latent(2)*100,4) '%)'])
            if app.FilenamesCheckBox.Value
                text(app.ProcrustesUIAxes, pc_pca(:,1),pc_pca(:,2), SampleNames, 'color', 'k', 'FontSize', 10, 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right', 'clipping','on');
            end
            title(app.ProcrustesUIAxes, 'Principal Component Analysis')
            IoU = [];
            if app.MorphospaceCheckBox.Value
                IoU = Compute_ConvexHull_IntersectionOverUnion(app,pca2_g1,pca2_g2);
            end
            if app.LegendCheckBox_2.Value
                if ~isempty(IoU)
                    leg = legend(app.ProcrustesUIAxes, app.g1_name,app.g2_name);
                    title(leg,strcat("IoU: ",num2str(IoU)));
                else
                    legend(app.ProcrustesUIAxes, app.g1_name,app.g2_name)
                end
            end
            hold(app.ProcrustesUIAxes, 'off')

            app.TextArea.Value = strcat("   >>");
            drawnow;
        end

        function IoU = Compute_ConvexHull_IntersectionOverUnion (app,c1,c2)
            %Convex hull of class 1
            [convex_hull_c1, area_c1] = convhull(c1(:,1),c1(:,2));
            
            %Convex hull of class 2
            [convex_hull_c2, area_c2] = convhull(c2(:,1),c2(:,2));
            
            %Plot both convex hulls
            plot(app.ProcrustesUIAxes, c1(convex_hull_c1,1),c1(convex_hull_c1,2),'b-')
            plot(app.ProcrustesUIAxes, c2(convex_hull_c2,1),c2(convex_hull_c2,2),'r-')
            
            %Find the exact intersection points between the convex hulls
            [xi,yi] = polyxpoly(c1(convex_hull_c1,1),c1(convex_hull_c1,2),c2(convex_hull_c2,1),c2(convex_hull_c2,2));
            
            %Find the vertices of one convex hull that lie inside the other
            vertices_chull_c1 = [c1(convex_hull_c1,1) c1(convex_hull_c1,2)];
            vertices_chull_c2 = [c2(convex_hull_c2,1) c2(convex_hull_c2,2)];
            
            %Find vertices of convex hull of class 1 inside convex hull of class 2
            vertices_chull_c1_in_c2_index = inpolygon(vertices_chull_c1(:,1), vertices_chull_c1(:,2), vertices_chull_c2(:,1), vertices_chull_c2(:,2));
            vertices_chull_c1_in_c2 = [vertices_chull_c1(vertices_chull_c1_in_c2_index,1) vertices_chull_c1(vertices_chull_c1_in_c2_index,2)];
            
            %Find vertices of convex hull of class 1 inside convex hull of class 2
            vertices_chull_c2_in_c1_index = inpolygon(vertices_chull_c2(:,1), vertices_chull_c2(:,2), vertices_chull_c1(:,1), vertices_chull_c1(:,2));
            vertices_chull_c2_in_c1 = [vertices_chull_c2(vertices_chull_c2_in_c1_index,1) vertices_chull_c2(vertices_chull_c2_in_c1_index,2)];
            
            %Create convex hull of the intersection
            points_intersection = [[xi yi] ; vertices_chull_c1_in_c2 ; vertices_chull_c2_in_c1];
            points_intersection_unique = unique(points_intersection, 'rows');

            if ~isempty(points_intersection)
                [convex_hull_intersection, area_intersection] = convhull(points_intersection_unique(:,1),points_intersection_unique(:,2));
                
                %Plot convex hull of the intersection
                plot(app.ProcrustesUIAxes, points_intersection_unique(convex_hull_intersection,1),points_intersection_unique(convex_hull_intersection,2),'g-')
                
                %Compute the intersection over union (IoU) data separability metric based 
                %on the areas of the convex hulls and of their intersection
                IoU = area_intersection/(area_c1+area_c2-area_intersection);
            else
                IoU = 0;
            end

        end

        %% EDMA
        % Distance calculation (FM -> Matrix with all differences between points. id_ij -> difference betwen point i & j)
        % Dimension -> C(n,k) = n!/k!(n-k)!
        function [FM, id_ij] = FM_creator(app, x, y, z)
            app.TextArea.Value = strcat("   >> Calculating distances");
            drawnow;
            FM = zeros(sum(1:size(x,2)-1),size(x,1));
            id_ij = [];
            for s=1:size(x,1)
                k = 1;
                for i=1:size(x,2)
                    S_i = [x(s,i), y(s,i), z(s,i)]; 
        
                    for j = i+1:size(x,2)
                        S_j = [x(s,j), y(s,j), z(s,j)]; 
                        FM(k,s) = (sum((S_i-S_j).^2)).^0.5;
        
                        if (s==1); id_ij   = [id_ij, [i;j]]; end
                        k       = k+1;
                    end
                end
                FM(:,s) = FM(:,s)./geomean(FM(:,s));
            end
            app.TextArea.Value = strcat("   >>");
            drawnow;
        end
        
        % Montecarlo analysis
        function [FDM_1vs2_boot, h_1vs2, CI_idx , mean_dist_1] = montecarlo(app, FM, IDX, alpha, num_boot)
            app.TextArea.Value = strcat("   >> Running montercarlo analysis.");
            drawnow;
        
            % Groups definition
            idx_1 = IDX==0;
            idx_2 = IDX==1;
            
            mean_dist_1 = mean(FM(:,idx_1),2);
            mean_dist_2 = mean(FM(:,idx_2),2);
            cov_1       = cov(FM(:,idx_1)');
            cov_2       = cov(FM(:,idx_2)');
            
            n_1 = sum(idx_1);
            n_2 = sum(idx_2);
        
            % Montecarlo
            CI_idx      = floor([num_boot*(alpha/2), num_boot*(1-(alpha/2))]);
            
            %FDM_1vs2    = mean_dist_1 - mean_dist_2;            
        
            mean_dist_1_boot = [];
            mean_dist_2_boot = [];
        
            for i = 1:num_boot
                mean_dist_1_boot       = [mean_dist_1_boot        ; mean(mvnrnd(mean_dist_1,cov_1,size(FM(:,idx_1),2)))];
                mean_dist_2_boot       = [mean_dist_2_boot        ; mean(mvnrnd(mean_dist_2,cov_2,size(FM(:,idx_2),2)))];
            end
            
            FDM_1vs2_boot      = sort(mean_dist_1_boot - mean_dist_2_boot);
            
            h_1vs2 = and(FDM_1vs2_boot(CI_idx(1),:)<=0,FDM_1vs2_boot(CI_idx(2),:)>=0); % Indica si es similar la diferencia entre ID 0 y 1

            app.TextArea.Value = strcat("   >>");
            drawnow;
        end
           
        function plot_Sdistances(app, id_ij,h_1vs2)
            app.TextArea.Value = strcat("   >> Plotting results.");
            drawnow;

            x = app.template_land_x';
            y = app.template_land_y';

            % Reset figures
            cla(app.EDMAUIAxes1)
            legend(app.EDMAUIAxes1,'off')
            cla(app.EDMAUIAxes2)
            legend(app.EDMAUIAxes2, 'off')
            chargeTemplatemodels(app)
                                  
            % Plot significative differences
            hold(app.EDMAUIAxes1,'on')
            plot(app.EDMAUIAxes1, [x(id_ij(1,not(h_1vs2))); x(id_ij(2,not(h_1vs2)))], [y(id_ij(1,not(h_1vs2))); y(id_ij(2,not(h_1vs2)))], 'Color',"#D95319");
            if app.LandmarksCheckBox.Value
                scatter(app.EDMAUIAxes1, app.template_land_x, app.template_land_y, 80, [0.00,0.18,0.38],'filled');
                % Add text labels for each point
                for i = 1:length(app.template_land_x)
                    text(app.EDMAUIAxes1, app.template_land_x(i), app.template_land_y(i), num2str(i), 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle','FontSize',8,'Color','white','clipping','on');
                end
            end
            diff_per = (sum(not(h_1vs2))/size(h_1vs2,2))*100;
            title(app.EDMAUIAxes1, ['Sig. Differences ' ' (' num2str(sum(not(h_1vs2)),'%d') ' of ' num2str(size(h_1vs2,2),'%d') ') - [' num2str(diff_per,'%.2f') '%]']);
            hold(app.EDMAUIAxes1,'off')
            
            % Plot significative similarities
            hold(app.EDMAUIAxes2,'on')
            plot(app.EDMAUIAxes2, [x(id_ij(1,h_1vs2)); x(id_ij(2,h_1vs2))], [y(id_ij(1,h_1vs2)); y(id_ij(2,h_1vs2))], 'Color', "#EDB120");
            if app.LandmarksCheckBox.Value
                scatter(app.EDMAUIAxes2, app.template_land_x, app.template_land_y, 80, [0.00,0.18,0.38],'filled');
                % Add text labels for each point
                for i = 1:length(app.template_land_x)
                    text(app.EDMAUIAxes2, app.template_land_x(i), app.template_land_y(i), num2str(i), 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle','FontSize',8,'Color','white','clipping','on');
                end
            end
            sim_per = (sum(h_1vs2)/size(h_1vs2,2))*100;
            title(app.EDMAUIAxes2, ['Sig. Similarities ' ' (' num2str(sum(h_1vs2),'%d') ' of ' num2str(size(h_1vs2,2),'%d') ') - [' num2str(sim_per,'%.2f') '%]']);
            hold(app.EDMAUIAxes2,'on')

            app.TextArea.Value = strcat("   >>");
            drawnow;
        end
        
        function plot_differences(app,FDM_1vs2_boot,CI_idx,h_1vs2,id_ij,mean_dist_1)
            app.TextArea.Value = strcat("   >> Plotting results.");
            drawnow;
            
            x = app.template_land_x';
            y = app.template_land_y';

            % Reset figures
            cla(app.EDMAUIAxes1)
            legend(app.EDMAUIAxes1,'off')
            cla(app.EDMAUIAxes2)
            legend(app.EDMAUIAxes2,'off')
            plottemplate(app,app.EDMAUIAxes1);
            plottemplate(app,app.EDMAUIAxes2);
                                  
            CI_diff=[FDM_1vs2_boot(CI_idx(1),not(h_1vs2))',FDM_1vs2_boot(CI_idx(2),not(h_1vs2))'];
            diff=mean(CI_diff,2); %2
            app.diff_excel = diff;
            [~,ix1]=sort(diff(diff>0),'descend');
            [~,ix2]=sort(diff(diff<0));
            id_diff=id_ij(:,not(h_1vs2)); %1
            app.id_diff_excel = id_diff;
            id_diff1=id_diff(:,diff>0);
            id_diff2=id_diff(:,diff<0);
            mean_dist_diff=mean_dist_1(not(h_1vs2));
            diff_rel = diff./mean_dist_diff; % 3 for excel
            app.diff_rel_excel = diff_rel;
            [diff_s_r1,ix_r1]=sort((diff(diff>0)./mean_dist_diff(diff>0)),'descend'); %3 sin ordenar
            [diff_s_r2,ix_r2]=sort((diff(diff<0)./mean_dist_diff(diff<0)));

       
            % Plot figure 1
            hold(app.EDMAUIAxes1 ,'on')
            plot(app.EDMAUIAxes1, [x(id_diff1(1,ix1(1))); x(id_diff1(2,ix1(1)))], [y(id_diff1(1,ix1(1))); y(id_diff1(2,ix1(1)))], 'b');
            plot(app.EDMAUIAxes1, [x(id_diff2(1,ix2(1))); x(id_diff2(2,ix2(1)))], [y(id_diff2(1,ix2(1))); y(id_diff2(2,ix2(1)))], 'r');
            plot(app.EDMAUIAxes1, [x(id_diff1(1,ix1(2:min(app.EDMA_numSLDIst.Value,length(ix1))))); ...
                x(id_diff1(2,ix1(2:min(app.EDMA_numSLDIst.Value,length(ix1)))))], [y(id_diff1(1,ix1(2:min(app.EDMA_numSLDIst.Value,length(ix1))))); y(id_diff1(2,ix1(2:min(app.EDMA_numSLDIst.Value,length(ix1)))))], 'b');
            plot(app.EDMAUIAxes1, [x(id_diff2(1,ix2(2:min(app.EDMA_numSLDIst.Value,length(ix2))))); x(id_diff2(2,ix2(2:min(app.EDMA_numSLDIst.Value,length(ix2)))))], ...
                [y(id_diff2(1,ix2(2:min(app.EDMA_numSLDIst.Value,length(ix2))))); y(id_diff2(2,ix2(2:min(app.EDMA_numSLDIst.Value,length(ix2)))))], 'r');
            if app.LegendCheckBox.Value
                legend(app.EDMAUIAxes1, strcat(app.g1_name," longer than"," ",app.g2_name), strcat(app.g1_name," shorter than ",app.g2_name),'AutoUpdate','off');
            end
            if app.LandmarksCheckBox.Value
                scatter(app.EDMAUIAxes1, app.template_land_x, app.template_land_y, 80, [0.00,0.18,0.38],'filled');
                % Add text labels for each point
                for i = 1:length(app.template_land_x)
                    text(app.EDMAUIAxes1, app.template_land_x(i), app.template_land_y(i), num2str(i), 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle','FontSize',8,'Color','white','clipping','on');
                end
            end
            title(app.EDMAUIAxes1, ['Top ' num2str(app.EDMA_numSLDIst.Value,'%d') ' absolute differences'])
            hold(app.EDMAUIAxes1 ,'off')
        
            % Plot figure 2
            hold(app.EDMAUIAxes2, 'on')
            plot(app.EDMAUIAxes2, [x(id_diff1(1,ix_r1(1))); x(id_diff1(2,ix_r1(1)))], [y(id_diff1(1,ix_r1(1))); y(id_diff1(2,ix_r1(1)))], 'b');
            plot(app.EDMAUIAxes2, [x(id_diff2(1,ix_r2(1))); x(id_diff2(2,ix_r2(1)))], [y(id_diff2(1,ix_r2(1))); y(id_diff2(2,ix_r2(1)))], 'r');
            plot(app.EDMAUIAxes2, [x(id_diff1(1,ix_r1(2:min(app.EDMA_numSLDIst.Value,length(ix_r1))))); x(id_diff1(2,ix_r1(2:min(app.EDMA_numSLDIst.Value,length(ix_r1)))))], ...
                [y(id_diff1(1,ix_r1(2:min(app.EDMA_numSLDIst.Value,length(ix_r1))))); y(id_diff1(2,ix_r1(2:min(app.EDMA_numSLDIst.Value,length(ix_r1)))))], 'b');
            plot(app.EDMAUIAxes2, [x(id_diff2(1,ix_r2(2:min(app.EDMA_numSLDIst.Value,length(ix_r2))))); x(id_diff2(2,ix_r2(2:min(app.EDMA_numSLDIst.Value,length(ix_r2)))))], ...
                [y(id_diff2(1,ix_r2(2:min(app.EDMA_numSLDIst.Value,length(ix_r2))))); y(id_diff2(2,ix_r2(2:min(app.EDMA_numSLDIst.Value,length(ix_r2)))))], 'r');
            if app.LegendCheckBox.Value
                legend(app.EDMAUIAxes2, strcat(app.g1_name," longer than"," ",app.g2_name),strcat(app.g1_name," shorter than ",app.g2_name),'AutoUpdate','off');
            end
            if app.LandmarksCheckBox.Value
                scatter(app.EDMAUIAxes2, app.template_land_x, app.template_land_y, 80, [0.00,0.18,0.38],'filled');
                % Add text labels for each point
                for i = 1:length(app.template_land_x)
                    text(app.EDMAUIAxes2, app.template_land_x(i), app.template_land_y(i), num2str(i), 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle','FontSize',8,'Color','white','clipping','on');
                end
            end
  
            if app.PercentagesCheckBox.Value
                n={};
                for i=1:min(app.EDMA_numSLDIst.Value,length(ix_r1)); n{end+1}=[num2str((diff_s_r1(i)*100),3),'%']; end
                text(app.EDMAUIAxes2, (x(id_diff1(1,ix_r1(1:min(app.EDMA_numSLDIst.Value,length(ix_r1)))))+x(id_diff1(2,ix_r1(1:min(app.EDMA_numSLDIst.Value,length(ix_r1))))))./2,(y(id_diff1(1,ix_r1(1:min(app.EDMA_numSLDIst.Value,length(ix_r1)))))+y(id_diff1(2,ix_r1(1:min(app.EDMA_numSLDIst.Value,length(ix_r1))))))./2, n, 'color', 'b', 'FontSize', 10, 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right','clipping','on');
                
                n={};
                for i=1:min(app.EDMA_numSLDIst.Value,length(ix_r2)); n{end+1}=[num2str((diff_s_r2(i)*100),3),'%']; end
                text(app.EDMAUIAxes2, (x(id_diff2(1,ix_r2(1:min(app.EDMA_numSLDIst.Value,length(ix_r2)))))+x(id_diff2(2,ix_r2(1:min(app.EDMA_numSLDIst.Value,length(ix_r2))))))./2,(y(id_diff2(1,ix_r2(1:min(app.EDMA_numSLDIst.Value,length(ix_r2)))))+y(id_diff2(2,ix_r2(1:min(app.EDMA_numSLDIst.Value,length(ix_r2))))))./2, n, 'color', 'r', 'FontSize', 10, 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right','clipping','on');
            end
            title(app.EDMAUIAxes2, ['Top ' num2str(app.EDMA_numSLDIst.Value,'%d') ' relative differences'])
            hold(app.EDMAUIAxes2, 'off')

            app.TextArea.Value = strcat("   >>");
            drawnow;
        end

        %%
        function chargeTemplatemodels(app)
            app.TextArea.Value = strcat("   >> Charging templates.");
            drawnow;

            % Read landmarks template
            try 
                switch (app.model_land)
                    case 20
                        pathToFile = fullfile(pwd, 'gui', 'resources', 'land_template_20.txt');
                        land_template = dlmread(pathToFile);
                    case 21
                        pathToFile = fullfile(pwd, 'gui', 'resources', 'land_template_21.txt');
                        land_template = dlmread(pathToFile);
                end
                app.template_land_y = land_template(:,1);
                app.template_land_x = land_template(:,2);
            catch
                uialert(app.BioFace3DUIFigure, 'Problem reading landmarks template.', 'Error', 'Icon', 'error');
                return;
            end

            % Plot face templates
            hold(app.EDMAUIAxes1,'on')
            plottemplate(app,app.EDMAUIAxes1)
            if app.LandmarksCheckBox.Value
                scatter(app.EDMAUIAxes1, app.template_land_x, app.template_land_y, 80, [0.00,0.18,0.38],'filled');
                % Add text labels for each point
                for i = 1:length(app.template_land_x)
                    text(app.EDMAUIAxes1, app.template_land_x(i), app.template_land_y(i), num2str(i), 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle','FontSize',8,'Color','white', 'clipping','on');
                end
            end
            hold(app.EDMAUIAxes1,'off')

            hold(app.EDMAUIAxes2,'on')
            plottemplate(app, app.EDMAUIAxes2);
            if app.LandmarksCheckBox.Value
                scatter(app.EDMAUIAxes2, app.template_land_x, app.template_land_y, 80, [0.00,0.18,0.38],'filled');
                % Add text labels for each point
                for i = 1:length(app.template_land_x)
                    text(app.EDMAUIAxes2, app.template_land_x(i), app.template_land_y(i), num2str(i), 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle','FontSize',8,'Color','white', 'clipping','on');
                end
            end
            hold(app.EDMAUIAxes2,'off')

            app.TextArea.Value = strcat("   >>");
            drawnow;
        end

        function plottemplate(app,UIAxes)
            try
                pathToMLAPP = fileparts(mfilename('fullpath'));
                pathTemplate = fullfile(pathToMLAPP, 'resources', 'faceTemplate.png');
                imshow(imread(pathTemplate), [], 'Parent', UIAxes);
            catch
                uialert(app.BioFace3DUIFigure, 'Unable to read face template image.', 'Error', 'Icon', 'error');
            end
        end

        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, path)
            % Read images
            app.BioFace3DUIFigure.Icon = fullfile(pwd, 'gui', 'resources', 'icon.png');
            app.Image_2.ImageSource = fullfile(pwd, 'resources', 'loading_ico.gif');
            app.Image.ImageSource = fullfile(pwd, 'resources', 'loading_ico.gif');
            app.ImageLogo_2.ImageSource = fullfile(pwd, 'resources', 'icon.png');
            app.ImageLogo.ImageSource = fullfile(pwd, 'resources', 'icon.png');
            app.SettingsButton.Icon = fullfile(pwd, 'gui', 'resources', 'set_ico.png');
            drawnow;
        
            % Charging image
            app.Image_2.Visible = "on";
            app.ImageLogo_2.Visible = "on";

            app.bioface_gui = caller;
            app.path_session = path;
            % app.path_session = 'C:\Users\alvaro.heredia\OneDrive - La Salle\DOCTORADO\02_Desarrollo\07_BioFace3D\_session_Biomark.json';

            readDataSession(app, app.path_session);

            % Leer archivos
            chargeTemplatemodels(app);
           
            % Load Data
            [app.X, app.Y, app.Z, app.files, app.ID_groups] = readExcelAndExtractLandmarks(app, app.g1_file, app.g2_file);

            % Charging image off
            app.Image_2.Visible = "off";
            app.ImageLogo_2.Visible = "off";
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            if app.valid_data
                try
                    %% Procrustes
                    % Charging image
                    app.Image_2.Visible = "on";
                    app.ImageLogo_2.Visible = "on";
    
                    if app.CentroidsizenormalizationCheckBox.Value
                    % Translation & scale
                    [pc_x, pc_y, pc_z] = scaling(app, app.X, app.Y, app.Z);
                    else
                        pc_x = app.X;
                        pc_y = app.Y;
                        pc_z = app.Z;
                    end
    
                    % Rotation
                    [pc_x_r, pc_y_r, pc_z_r] = rotation(app, pc_x, pc_y, pc_z);
                    
                    % Projection over tangent plane
                    PC_T = procrustes(app, pc_x,pc_y,pc_z,pc_x_r,pc_y_r,pc_z_r);
                    
                    % PCA
                    [~,app.pc_pca,app.pca_latent] = pca(PC_T);
                    app.pc_pca2 = app.pc_pca(:,1:2);
                    updatePCA(app, app.pc_pca2, app.files, app.ID_groups, app.pca_latent)
    
                    % Enable panels
                    app.PanelPlot_2.Enable = "on";
                    app.PanelSave.Enable = "on";
                catch
                    uialert(app.BioFace3DUIFigure, 'Problem computing biomarkers. Check number of landmarks and Excel files.', 'Error', 'Icon', 'error');
                end

                % Charging image off
                app.Image_2.Visible = "off";
                app.ImageLogo_2.Visible = "off";
            else
                uialert(app.BioFace3DUIFigure, 'Problem opening excel files. Invalid format. Change files.', 'Error', 'Icon', 'error');
            end
        end

        % Button pushed function: ComputeButton_2
        function ComputeButton_2Pushed(app, event)
            if app.valid_data
                try 
                    %% EDMA
                    % Charging image
                    app.Image.Visible = "on";
                    app.ImageLogo.Visible = "on";
    
                    % Translation & scale
                    [edma_x, edma_y, edma_z] = scaling(app, app.X, app.Y, app.Z);
    
                    % Distance calculation
                    [FM, app.EDMA_id_ij] = FM_creator(app, edma_x, edma_y, edma_z);
    
                    % Montecarlo
                    [app.EDMA_FDM_1vs2_boot, app.EDMA_h_1vs2, app.EDMA_CI_idx , app.EDMA_mean_dist_1] = montecarlo(app, FM, app.ID_groups, app.AlphaSpinner.Value, app.IterationsSpinner.Value);
    
                    % Plot
                    plot_Sdistances(app, app.EDMA_id_ij,app.EDMA_h_1vs2);
                    app.ShowdistancesButton.Value = true;
                    app.PlotButtonGroup.Enable = 'on';
                    app.PanelPlot.Enable = "on";
                    app.PanelSave_2.Enable = "on";
                catch
                    uialert(app.BioFace3DUIFigure, 'Problem computing biomarkers. Check number of landmarks and Excel files.', 'Error', 'Icon', 'error');
                end

                % Charging image off
                app.Image.Visible = "off";
                app.ImageLogo.Visible = "off";
            else
                uialert(app.BioFace3DUIFigure, 'Problem opening excel files. Invalid format. Change files.', 'Error', 'Icon', 'error');
            end
        end

        % Selection changed function: PlotButtonGroup
        function PlotButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotButtonGroup.SelectedObject;
            % Charging image
            app.Image.Visible = "on";
            app.ImageLogo.Visible = "on";
            switch(selectedButton.Text)
                case "Show distances"
                    plot_Sdistances(app, app.EDMA_id_ij,app.EDMA_h_1vs2);
                case "Show differences"
                    plot_differences(app, app.EDMA_FDM_1vs2_boot,app.EDMA_CI_idx,app.EDMA_h_1vs2,app.EDMA_id_ij,app.EDMA_mean_dist_1)
            end

            % Charging image
            app.Image.Visible = "off";
            app.ImageLogo.Visible = "off";
        end

        % Value changed function: EDMA_numSLDIst
        function EDMA_numSLDIstValueChanged(app, event)
            % Charging image
            app.Image.Visible = "on";
            app.ImageLogo.Visible = "on";
            % Update plots
            app.ShowdifferencesButton.Value = true;
            plot_differences(app, app.EDMA_FDM_1vs2_boot,app.EDMA_CI_idx,app.EDMA_h_1vs2,app.EDMA_id_ij,app.EDMA_mean_dist_1);

            % Charging image
            app.Image.Visible = "off";
            app.ImageLogo.Visible = "off";
        end

        % Value changed function: LandmarksCheckBox
        function LandmarksCheckBoxValueChanged(app, event)
            % Charging image
            app.Image.Visible = "on";
            app.ImageLogo.Visible = "on";
            % Update plots
            selectedButton = app.PlotButtonGroup.SelectedObject;
            switch(selectedButton.Text)
                case "Show distances"
                    plot_Sdistances(app, app.EDMA_id_ij,app.EDMA_h_1vs2);
                case "Show differences"
                    plot_differences(app, app.EDMA_FDM_1vs2_boot,app.EDMA_CI_idx,app.EDMA_h_1vs2,app.EDMA_id_ij,app.EDMA_mean_dist_1)
            end
            % Charging image off
            app.Image.Visible = "off";
            app.ImageLogo.Visible = "of";
        end

        % Value changed function: LegendCheckBox
        function LegendCheckBoxValueChanged(app, event)
            % Charging image
            app.Image.Visible = "on";
            app.ImageLogo.Visible = "on";
            % Update plot
            app.ShowdifferencesButton.Value = true;
            plot_differences(app, app.EDMA_FDM_1vs2_boot,app.EDMA_CI_idx,app.EDMA_h_1vs2,app.EDMA_id_ij,app.EDMA_mean_dist_1);
            % Charging image
            app.Image.Visible = "off";
            app.ImageLogo.Visible = "off";
        end

        % Value changed function: PercentagesCheckBox
        function PercentagesCheckBoxValueChanged(app, event)
            % Charging image
            app.Image.Visible = "on";
            app.ImageLogo.Visible = "on";
            % Update plot
            app.ShowdifferencesButton.Value = true;
            plot_differences(app, app.EDMA_FDM_1vs2_boot,app.EDMA_CI_idx,app.EDMA_h_1vs2,app.EDMA_id_ij,app.EDMA_mean_dist_1);
            % Charging image off
            app.Image.Visible = "off";
            app.ImageLogo.Visible = "off";
        end

        % Value changed function: FilenamesCheckBox
        function FilenamesCheckBoxValueChanged(app, event)
            % Charging image
            app.Image_2.Visible = "on";
            app.ImageLogo_2.Visible = "on";
            % Update PCA
            updatePCA(app, app.pc_pca2, app.files, app.ID_groups,app.pca_latent)
            % Charging image off
            app.Image_2.Visible = "off";
            app.ImageLogo_2.Visible = "off";
        end

        % Value changed function: LegendCheckBox_2
        function LegendCheckBox_2ValueChanged(app, event)
            % Charging image
            app.Image_2.Visible = "on";
            app.ImageLogo_2.Visible = "on";
            % Update PCA
            updatePCA(app, app.pc_pca2, app.files, app.ID_groups,app.pca_latent)
            % Charging image off
            app.Image_2.Visible = "off";
            app.ImageLogo_2.Visible = "off";
        end

        % Button pushed function: SavefigureButton
        function SavefigureButtonPushed(app, event)
            % Get the full file path (name and directory) using uiputfile
            [fileName, filePath, filterIndex] = uiputfile({'*.png', 'PNG Files (*.png)'; '*.jpg', 'JPEG Files (*.jpg)'; '*.fig', 'MATLAB Figure (*.fig)'}, 'Save Figure');
        
            % Charging image
            app.Image_2.Visible = "on";
            app.ImageLogo_2.Visible = "on";
            app.TextArea.Value = strcat("   >> Saving figure.");
            drawnow;
            
            try
                % Check if the user no clicked on "Cancel"
                if filterIndex ~= 0
                % Create the full file path
                fullFilePath = fullfile(filePath, fileName);
            
                % Save the figure in the selected format
                exportgraphics(app.ProcrustesUIAxes, fullFilePath);
                uialert(app.BioFace3DUIFigure, 'Image save succesfully.', 'Success', 'Icon', 'success');
                end
            catch
                uialert(app.BioFace3DUIFigure, 'Problem exporting figure to image.', 'Error', 'Icon', 'error');
            end
            
            % Charging image
            app.Image_2.Visible = "off";
            app.ImageLogo_2.Visible = "off";
            app.TextArea.Value = strcat("   >> ");
            drawnow;
        end

        % Button pushed function: SaveExcelButton
        function SaveExcelButtonPushed(app, event)
             % Charging image
            app.Image_2.Visible = "on";
            app.ImageLogo_2.Visible = "on";
            app.TextArea.Value = strcat("   >> Saving Excel.");
            drawnow;
            
            % Save procrustes coordinates and PCA values of each file
            try
                % Create table coordinates projections
                coord_table = array2table(app.procrustes_coord);
                base_table = table(app.files,'VariableNames',{'Files'});
                cordTable = horzcat(base_table, coord_table);

                % Create the table PCA
                pca_table = array2table(app.pc_pca);
                base_table = table(app.files,'VariableNames',{'Files'});
                pcaTable = horzcat(base_table, pca_table);
            
                % Save the table to an Excel file
                output_file = strcat(app.output_path,filesep,app.session_name,'_gpa_biomarkers.xlsx');
                writetable(cordTable, output_file, 'Sheet', 'Procrustes coordinates');
                writetable(pcaTable, output_file, 'Sheet', 'Principal Components (PC)');

                uialert(app.BioFace3DUIFigure, 'Excel generated succesfully.', 'Success', 'Icon', 'success');
            catch
                uialert(app.BioFace3DUIFigure, 'Problem generating Excel file.', 'Error', 'Icon', 'error');
            end

            % Charging image
            app.Image_2.Visible = "off";
            app.ImageLogo_2.Visible = "off";
            app.TextArea.Value = strcat("   >>");
            drawnow;          
        end

        % Button pushed function: SavefiguresButton
        function SavefiguresButtonPushed(app, event)
            % Get the full file path (name and directory) using uiputfile
            [fileName, filePath, filterIndex] = uiputfile({'*.png', 'PNG Files (*.png)'; '*.jpg', 'JPEG Files (*.jpg)'; '*.fig', 'MATLAB Figure (*.fig)'}, 'Save Figure');
          
            % Charging image
            app.Image.Visible = "on";
            app.ImageLogo.Visible = "on";
            app.TextArea.Value = strcat("   >> Saving figure(s).");
            drawnow;

            try
                % Check if the user no clicked on "Cancel"
                if filterIndex ~= 0

                    if app.DiffCheckBox.Value
                        plot_Sdistances(app, app.EDMA_id_ij,app.EDMA_h_1vs2);
                        exportgraphics(app.EDMAUIAxes1, fullfile(filePath,strcat(fileName(1:end-4),'_diff',fileName(end-3:end))));
                        app.ShowdifferencesButton.Value = true;
                    end

                    if app.NodiffCheckBox.Value
                        plot_Sdistances(app, app.EDMA_id_ij,app.EDMA_h_1vs2);
                        exportgraphics(app.EDMAUIAxes2, fullfile(filePath,strcat(fileName(1:end-4),'_Nodiff',fileName(end-3:end))));
                        app.ShowdifferencesButton.Value = true;
                    end

                    if app.AbsoluteCheckBox.Value
                        plot_differences(app, app.EDMA_FDM_1vs2_boot,app.EDMA_CI_idx,app.EDMA_h_1vs2,app.EDMA_id_ij,app.EDMA_mean_dist_1)
                        exportgraphics(app.EDMAUIAxes1, fullfile(filePath,strcat(fileName(1:end-4),'_absDist',fileName(end-3:end))));
                        app.ShowdistancesButton.Value = true;
                    end

                    if app.RelativeCheckBox.Value
                        plot_differences(app, app.EDMA_FDM_1vs2_boot,app.EDMA_CI_idx,app.EDMA_h_1vs2,app.EDMA_id_ij,app.EDMA_mean_dist_1)
                        exportgraphics(app.EDMAUIAxes2, fullfile(filePath,strcat(fileName(1:end-4),'_relDist',fileName(end-3:end))));
                        app.ShowdistancesButton.Value = true;
                    end

                    % Save the figure in the selected format
                    uialert(app.BioFace3DUIFigure, 'Image save succesfully.', 'Success', 'Icon', 'success');
                end
            catch
                uialert(app.BioFace3DUIFigure, 'Problem exporting figure to image.', 'Error', 'Icon', 'error');
            end

            % Charging image
            app.Image.Visible = "off";
            app.ImageLogo.Visible = "off";
            app.TextArea.Value = strcat("   >> ");
            drawnow;
        end

        % Button pushed function: SettingsButton
        function SettingsButtonPushed(app, event)

            % Charging image
            app.Image_2.Visible = "on";
            app.ImageLogo_2.Visible = "on";
            app.Image.Visible = "on";
            app.ImageLogo.Visible = "on";

            %% BORRARRRR
            % app.tmp_path = 'C:\Users\alvaro.heredia\OneDrive - La Salle\DOCTORADO\02_Desarrollo\07_BioFace3D';
            % app.session_name = 'Biomark';
            % path_json = strcat(app.tmp_path, filesep, ['_session_' app.session_name '.json']);

            app.settings_gui = settingsBiomarkers(app, app.path_session);
            waitfor(app.settings_gui);

            % Update data
            readDataSession(app, app.path_session);

            % Leer archivos
            chargeTemplatemodels(app);
           
            % Load Data
            [app.X, app.Y, app.Z, app.files, app.ID_groups] = readExcelAndExtractLandmarks(app, app.g1_file, app.g2_file);

            % Charging image off
            app.Image_2.Visible = "off";
            app.ImageLogo_2.Visible = "off";
            app.Image.Visible = "off";
            app.ImageLogo.Visible = "off";
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            % Delete app
            delete(app)
        end

        % Menu selected function: SettingsMenu
        function SettingsMenuSelected(app, event)
            % Charging image
            app.Image_2.Visible = "on";
            app.ImageLogo_2.Visible = "on";
            app.Image.Visible = "on";
            app.ImageLogo.Visible = "on";

            %% BORRARRRR
            % app.tmp_path = 'C:\Users\alvaro.heredia\OneDrive - La Salle\DOCTORADO\02_Desarrollo\07_BioFace3D';
            % app.session_name = 'Biomark';
            % path_json = strcat(app.tmp_path, filesep, ['_session_' app.session_name '.json']);

            app.settings_gui = settingsBiomarkers(app, app.path_session);
            waitfor(app.settings_gui);

            % Update data
            readDataSession(app, app.path_session);

            % Leer archivos
            chargeTemplatemodels(app);
           
            % Load Data
            [app.X, app.Y, app.Z, app.files, app.ID_groups] = readExcelAndExtractLandmarks(app, app.g1_file, app.g2_file);

            % Charging image off
            app.Image_2.Visible = "off";
            app.ImageLogo_2.Visible = "off";
            app.Image.Visible = "off";
            app.ImageLogo.Visible = "off";
        end

        % Menu selected function: NewsessionMenu
        function NewsessionMenuSelected(app, event)
            answer = uiconfirm(app.BioFace3DUIFigure, ['Do you want to close the current session (', app.session_name, ') and start a new one?'], ...
                'Confirmation', 'Options', {'Yes', 'Cancel'}, 'DefaultOption', 'Yes');
            if strcmp(answer,'Yes')
                app.bioface_gui = BioFace3D();
                delete(app)
            end
        end

        % Button pushed function: SaveExcelButton_2
        function SaveExcelButton_2Pushed(app, event)
            %Plot differences 
            app.ShowdifferencesButton.Value = true;
            plot_differences(app, app.EDMA_FDM_1vs2_boot,app.EDMA_CI_idx,app.EDMA_h_1vs2,app.EDMA_id_ij,app.EDMA_mean_dist_1);
            
            % Charging image
            app.Image.Visible = "on";
            app.ImageLogo.Visible = "on";
            app.TextArea.Value = strcat("   >> Generating Excel.");
            drawnow;

            % Save absolute and relative distances between different points
            try
                % Create the table     
                id_diff_table = array2table(app.id_diff_excel','VariableNames',{'Land1','Land2'});
                dist_table = table(app.diff_excel,app.diff_rel_excel,'VariableNames',{'Abs.Dist','Rel.Dist'});
                edmaTable = horzcat(id_diff_table, dist_table);
            
                % Save the table to an Excel file
                output_file = strcat(app.output_path,filesep,app.session_name,'_distances_edma.xlsx');
                writetable(edmaTable, output_file);

                uialert(app.BioFace3DUIFigure, 'Excel generated succesfully.', 'Success', 'Icon', 'success');
            catch
                uialert(app.BioFace3DUIFigure, 'Problem generating Excel file.', 'Error', 'Icon', 'error');
            end

            app.Image.Visible = "off";
            app.ImageLogo.Visible = "off";
            app.TextArea.Value = strcat("   >>");
            drawnow;
        end

        % Value changed function: MorphospaceCheckBox
        function MorphospaceCheckBoxValueChanged(app, event)
            % Charging image
            app.Image_2.Visible = "on";
            app.ImageLogo_2.Visible = "on";
            % Update PCA
            updatePCA(app, app.pc_pca2, app.files, app.ID_groups,app.pca_latent)
            % Charging image off
            app.Image_2.Visible = "off";
            app.ImageLogo_2.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BioFace3DUIFigure = uifigure('Visible', 'off');
            app.BioFace3DUIFigure.Name = 'BioFace3D';
            app.SessionMenu = uimenu(app.BioFace3DUIFigure);
            app.SessionMenu.Text = 'Session';
            app.NewsessionMenu = uimenu(app.SessionMenu);
            app.NewsessionMenu.MenuSelectedFcn = createCallbackFcn(app, @NewsessionMenuSelected, true);
            app.NewsessionMenu.Accelerator = 'N';
            app.NewsessionMenu.Text = 'New session';
            app.SettingsMenu = uimenu(app.SessionMenu);
            app.SettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SettingsMenuSelected, true);
            app.SettingsMenu.Text = 'Settings';
            app.ExitMenu = uimenu(app.SessionMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.Menu_3 = uimenu(app.BioFace3DUIFigure);
            app.Toolbar = uitoolbar(app.BioFace3DUIFigure);
            app.TabGroup = uitabgroup(app.BioFace3DUIFigure);
            app.ProcrustesGPATab = uitab(app.TabGroup);
            app.ProcrustesGPATab.Title = 'Procrustes (GPA)';
            app.ProcrustesUIAxes = uiaxes(app.ProcrustesGPATab);
            app.GeneralizedProcrustesAnalysisLabel = uilabel(app.ProcrustesGPATab);
            app.GeneralizedProcrustesAnalysisLabel.Text = 'Generalized Procrustes Analysis';
            app.Panel_2 = uipanel(app.ProcrustesGPATab);
            app.ComputeButton = uibutton(app.Panel_2, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.CentroidsizenormalizationCheckBox = uicheckbox(app.Panel_2);
            app.CentroidsizenormalizationCheckBox.Text = 'Centroid size normalization';
            app.CentroidsizenormalizationCheckBox.Value = true;
            app.PanelPlot_2 = uipanel(app.ProcrustesGPATab);
            app.PanelPlot_2.Enable = 'off';
            app.FilenamesCheckBox = uicheckbox(app.PanelPlot_2);
            app.FilenamesCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilenamesCheckBoxValueChanged, true);
            app.FilenamesCheckBox.Text = 'Filenames';
            app.FilenamesCheckBox.Value = true;
            app.LegendCheckBox_2 = uicheckbox(app.PanelPlot_2);
            app.LegendCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @LegendCheckBox_2ValueChanged, true);
            app.LegendCheckBox_2.Text = 'Legend';
            app.LegendCheckBox_2.Value = true;
            app.MorphospaceCheckBox = uicheckbox(app.PanelPlot_2);
            app.MorphospaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @MorphospaceCheckBoxValueChanged, true);
            app.MorphospaceCheckBox.Text = 'Morphospace';
            app.MorphospaceCheckBox.Value = true;
            app.PanelSave = uipanel(app.ProcrustesGPATab);
            app.PanelSave.Enable = 'off';
            app.SavefigureButton = uibutton(app.PanelSave, 'push');
            app.SavefigureButton.ButtonPushedFcn = createCallbackFcn(app, @SavefigureButtonPushed, true);
            app.SavefigureButton.Text = 'Save figure';
            app.SaveExcelButton = uibutton(app.PanelSave, 'push');
            app.SaveExcelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExcelButtonPushed, true);
            app.SaveExcelButton.Text = 'Save Excel';
            app.Image_2 = uiimage(app.ProcrustesGPATab);
            app.Image_2.Visible = 'off';
            app.ImageLogo_2 = uiimage(app.ProcrustesGPATab);
            app.ImageLogo_2.Visible = 'off';
            app.EDMATab = uitab(app.TabGroup);
            app.EDMATab.Title = 'EDMA';
            app.EDMAUIAxes1 = uiaxes(app.EDMATab);
            app.EDMAUIAxes1.XTick = [];
            app.EDMAUIAxes1.XTickLabel = '';
            app.EDMAUIAxes1.YTick = [];
            app.EDMAUIAxes2 = uiaxes(app.EDMATab);
            app.EDMAUIAxes2.XTick = [];
            app.EDMAUIAxes2.XTickLabel = '';
            app.EDMAUIAxes2.YTick = [];
            app.EuclideanDistanceMatrixAnalysisLabel = uilabel(app.EDMATab);
            app.EuclideanDistanceMatrixAnalysisLabel.Text = 'Euclidean Distance Matrix Analysis';
            app.PlotButtonGroup = uibuttongroup(app.EDMATab);
            app.PlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotButtonGroupSelectionChanged, true);
            app.PlotButtonGroup.Enable = 'off';
            app.ShowdistancesButton = uiradiobutton(app.PlotButtonGroup);
            app.ShowdistancesButton.Text = 'Show distances';
            app.ShowdistancesButton.Value = true;
            app.ShowdifferencesButton = uiradiobutton(app.PlotButtonGroup);
            app.ShowdifferencesButton.Text = 'Show differences';
            app.Panel = uipanel(app.EDMATab);
            app.AlphaSpinnerLabel = uilabel(app.Panel);
            app.AlphaSpinnerLabel.Text = 'Alpha';
            app.AlphaSpinner = uispinner(app.Panel);
            app.AlphaSpinner.Step = 0.01;
            app.AlphaSpinner.Limits = [0 1];
            app.AlphaSpinner.ValueDisplayFormat = '%1.2f';
            app.AlphaSpinner.Value = 0.1;
            app.IterationsSpinnerLabel = uilabel(app.Panel);
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.IterationsSpinner = uispinner(app.Panel);
            app.IterationsSpinner.Limits = [0 Inf];
            app.IterationsSpinner.Value = 2000;
            app.ComputeButton_2 = uibutton(app.Panel, 'push');
            app.ComputeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ComputeButton_2Pushed, true);
            app.ComputeButton_2.Text = 'Compute';
            app.PanelPlot = uipanel(app.EDMATab);
            app.PanelPlot.Enable = 'off';
            app.ShorterLongerDistancesSpinnerLabel = uilabel(app.PanelPlot);
            app.ShorterLongerDistancesSpinnerLabel.Text = {'Shorter/Longer'; 'Distances'};
            app.EDMA_numSLDIst = uispinner(app.PanelPlot);
            app.EDMA_numSLDIst.Limits = [1 Inf];
            app.EDMA_numSLDIst.ValueChangedFcn = createCallbackFcn(app, @EDMA_numSLDIstValueChanged, true);
            app.EDMA_numSLDIst.Value = 1;
            app.LandmarksCheckBox = uicheckbox(app.PanelPlot);
            app.LandmarksCheckBox.ValueChangedFcn = createCallbackFcn(app, @LandmarksCheckBoxValueChanged, true);
            app.LandmarksCheckBox.Text = 'Landmarks';
            app.LandmarksCheckBox.Value = true;
            app.PercentagesCheckBox = uicheckbox(app.PanelPlot);
            app.PercentagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PercentagesCheckBoxValueChanged, true);
            app.PercentagesCheckBox.Text = 'Percentages';
            app.PercentagesCheckBox.Value = true;
            app.LegendCheckBox = uicheckbox(app.PanelPlot);
            app.LegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendCheckBoxValueChanged, true);
            app.LegendCheckBox.Text = 'Legend';
            app.LegendCheckBox.Value = true;
            app.Image = uiimage(app.EDMATab);
            app.Image.Visible = 'off';
            app.ImageLogo = uiimage(app.EDMATab);
            app.ImageLogo.Visible = 'off';
            app.PanelSave_2 = uipanel(app.EDMATab);
            app.PanelSave_2.Enable = 'off';
            app.SavefiguresButton = uibutton(app.PanelSave_2, 'push');
            app.SavefiguresButton.ButtonPushedFcn = createCallbackFcn(app, @SavefiguresButtonPushed, true);
            app.SavefiguresButton.Text = 'Save figures';
            app.SaveExcelButton_2 = uibutton(app.PanelSave_2, 'push');
            app.SaveExcelButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveExcelButton_2Pushed, true);
            app.SaveExcelButton_2.Text = 'Save Excel';
            app.DiffCheckBox = uicheckbox(app.PanelSave_2);
            app.DiffCheckBox.Text = 'Diff.';
            app.DiffCheckBox.Value = true;
            app.NodiffCheckBox = uicheckbox(app.PanelSave_2);
            app.NodiffCheckBox.Text = 'No diff.';
            app.NodiffCheckBox.Value = true;
            app.AbsoluteCheckBox = uicheckbox(app.PanelSave_2);
            app.AbsoluteCheckBox.Text = 'Absolute';
            app.AbsoluteCheckBox.Value = true;
            app.RelativeCheckBox = uicheckbox(app.PanelSave_2);
            app.RelativeCheckBox.Text = 'Relative';
            app.RelativeCheckBox.Value = true;
            app.TextArea = uitextarea(app.BioFace3DUIFigure);
            app.TextArea.Editable = 'off';
            app.Label = uilabel(app.BioFace3DUIFigure);
            app.Label.Text = '-------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------';
            app.StatusLabel = uilabel(app.BioFace3DUIFigure);
            app.StatusLabel.Text = 'Status';
            app.SessionNameLabel = uilabel(app.BioFace3DUIFigure);
            app.SessionNameLabel.Text = 'SESSION NAME';
            app.dataSessionLabel = uilabel(app.BioFace3DUIFigure);
            app.dataSessionLabel.Text = 'Facial Biomarkers';
            app.filesNumberLabel = uilabel(app.BioFace3DUIFigure);
            app.filesNumberLabel.Text = 'Group 1:';
            app.SettingsButton = uibutton(app.BioFace3DUIFigure, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.SettingsButton.Text = 'Settings';
            app.LabelG1Name = uilabel(app.BioFace3DUIFigure);
            app.LabelG1Name.Text = '';
            app.filesNumberLabel_2 = uilabel(app.BioFace3DUIFigure);
            app.filesNumberLabel_2.Text = 'Group 2:';
            app.LabelG2Name = uilabel(app.BioFace3DUIFigure);
            app.LabelG2Name.Text = '';
            app.vsLabel = uilabel(app.BioFace3DUIFigure);
            app.vsLabel.Text = 'vs';
            % Show the figure after all components are created
            app.BioFace3DUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Biomarkers(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BioFace3DUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.BioFace3DUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BioFace3DUIFigure)
        end
    end
end