classdef MRI_processing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BioFace3DUIFigure          matlab.ui.Figure
        SessionMenu                matlab.ui.container.Menu
        NewsessionMenu             matlab.ui.container.Menu
        SettingsMenu               matlab.ui.container.Menu
        ExitMenu                   matlab.ui.container.Menu
        Toolbar                    matlab.ui.container.Toolbar
        PanelProcessingList        matlab.ui.container.Panel
        StopButton                 matlab.ui.control.StateButton
        TimeLabel                  matlab.ui.control.Label
        FileListLabel              matlab.ui.control.Label
        ProcessingfilesLabel       matlab.ui.control.Label
        LoadingLogo_2              matlab.ui.control.Image
        Loading_2                  matlab.ui.control.Image
        PanelLoadingPLY            matlab.ui.container.Panel
        ImageLogo_3                matlab.ui.control.Image
        DisplayingmeshinanewwindowLabel  matlab.ui.control.Label
        Image_4                    matlab.ui.control.Image
        PanelLoading               matlab.ui.container.Panel
        PanelLoading_2             matlab.ui.container.Panel
        ImageLogo_2                matlab.ui.control.Image
        LoadingLabel_2             matlab.ui.control.Label
        Image_3                    matlab.ui.control.Image
        ImageLogo                  matlab.ui.control.Image
        LoadingLabel               matlab.ui.control.Label
        Image_2                    matlab.ui.control.Image
        Label_2                    matlab.ui.control.Label
        IndividualProcessingPanel  matlab.ui.container.Panel
        LoadingLogo                matlab.ui.control.Image
        Loading                    matlab.ui.control.Image
        LabelFile                  matlab.ui.control.Label
        EndButton                  matlab.ui.control.Button
        ContinueButton             matlab.ui.control.Button
        SavetemporalfilesCheckBox  matlab.ui.control.CheckBox
        Step4Panel                 matlab.ui.container.Panel
        PanelThresholdFace         matlab.ui.container.Panel
        SeemeshFaceButton          matlab.ui.control.Button
        ThresholdZSpinner          matlab.ui.control.Spinner
        ThresholdZSpinner_2Label   matlab.ui.control.Label
        ThresholdYSpinner          matlab.ui.control.Spinner
        ThresholdYSpinner_2Label   matlab.ui.control.Label
        SwitchStep4                matlab.ui.control.Switch
        FaceextractionLabel        matlab.ui.control.Label
        STEP4Lamp                  matlab.ui.control.Lamp
        STEP4LampLabel             matlab.ui.control.Label
        Step3Panel                 matlab.ui.container.Panel
        HeadprocessingLabel        matlab.ui.control.Label
        STEP3Lamp                  matlab.ui.control.Lamp
        STEP3LampLabel             matlab.ui.control.Label
        Step2Panel                 matlab.ui.container.Panel
        PanelThreshold             matlab.ui.container.Panel
        SeemeshButton              matlab.ui.control.Button
        ThresholdSpinner           matlab.ui.control.Spinner
        ThresholdSpinnerLabel      matlab.ui.control.Label
        SwitchStep2                matlab.ui.control.Switch
        HeadreconstructionLabel    matlab.ui.control.Label
        STEP2Lamp                  matlab.ui.control.Lamp
        STEP2LampLabel             matlab.ui.control.Label
        Step1Panel                 matlab.ui.container.Panel
        SliceprocessingenhancementLabel  matlab.ui.control.Label
        STEP1Lamp                  matlab.ui.control.Lamp
        STEP1LampLabel             matlab.ui.control.Label
        ProcessingLabel            matlab.ui.control.Label
        dataSessionLabel           matlab.ui.control.Label
        SessionNameLabel           matlab.ui.control.Label
        filesNumberLabel           matlab.ui.control.Label
        OutputfacialmeshLabel      matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        SettingsButton             matlab.ui.control.Button
        ProcessingButton           matlab.ui.control.Button
        TreeProcessed              matlab.ui.container.Tree
        ProcessedNode              matlab.ui.container.TreeNode
        StatusLabel                matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
        ChangefolderButton         matlab.ui.control.Button
        InputfilesLabel            matlab.ui.control.Label
        Tree                       matlab.ui.container.CheckBoxTree
        InputNode                  matlab.ui.container.TreeNode
    end

    
    properties (Access = private)
        session_name
        input_path
        output_path
        session_path
        path_tmp_dir
        processingOne_filename
        batch_alignment
        save_snapshot
        smooth_remesh
    end
    
    properties (Access = public)
        newSession_gui
        settingsProcessingGUI
        bioface_gui
        % Enviroment
        enviroment_path
        installation_path
    end
     
    methods (Access = private)
        function readDataSession(app, fullFilePath)
            try
                % Read JSON from the file
                jsonStr = fileread(fullFilePath);
    
                % Convert JSON to a MATLAB structure
                jsonData = jsondecode(jsonStr);
    
                % Access the values from the structure
                app.session_name = jsonData.session.name;
                app.input_path = jsonData.session.input_path;
                app.output_path = jsonData.session.output_path;
                app.batch_alignment = jsonData.mri_processing.batch_alignment;
                app.save_snapshot = jsonData.mri_processing.save_snapshot;
                app.smooth_remesh = jsonData.mri_processing.smooth_remesh;

                % Display values in text labels (replace 'LabelName', 'LabelInputPath', 'LabelOutputPath' with actual labels)
                app.SessionNameLabel.Text = ['Session: ' app.session_name];
    
            catch
                uialert(app.BioFace3DUIFigure, 'Error reading or displaying session values', 'Error', 'Icon', 'error');
            end
        end

        % Callback for the button that reads and displays files in a Tree
        function ListFilesPushed(app, ~)
            % Get a list of all files in the selected directory
            files = dir(fullfile(app.input_path, '*.nii*'));

            % Filter out directories from the list
            files = files(~[files.isdir]);

            % Number of files
            app.filesNumberLabel.Text = strcat(num2str(numel(files)), ' files in folder');

            % Display valid files in a Tree (replace 'uitree1' with the actual Tree component)
            app.InputNode.Text = app.input_path;
            drawnow;
            for i = 1:numel(files)
                fileNode = uitreenode(app.InputNode,'Text', files(i).name, 'NodeData', []);
            end
            drawnow;
            
        end

        function updateDataSession(app, path)
            try
                % Read the JSON file and decode it into a MATLAB structure
                jsonData = jsondecode(fileread(path));
                
                % Update or modify fields in the MATLAB structure
                jsonData.session.input_path = app.input_path;
                jsonData.session.output_path = app.output_path;
                jsonData.session.name = app.session_name;
                
                % Encode the MATLAB structure back into JSON format
                updatedJsonString = jsonencode(jsonData,'PrettyPrint',true);
                
                % Write the updated JSON string back to the file
                fid = fopen(path, 'w');
                if fid > 0
                    fprintf(fid, '%s', updatedJsonString);
                    fclose(fid);
                end
            catch
                uialert(app.BioFace3DUIFigure, 'Unable to update settings parameters', 'Error', 'Icon', 'error');
            end
        end

        % Check files to process
        function [files_dir, num_files] = getFilesDir(app)
            app.TextArea.Value = strcat("   >> Reading files to process.");
            drawnow;

            if length(app.Tree.CheckedNodes) == 1
                if exist(strcat(app.input_path,filesep,app.Tree.CheckedNodes(1).Text), 'file') == 2
                    files_dir = strcat(app.input_path,filesep,app.Tree.CheckedNodes(1).Text);
                    num_files = 1;
                else
                    files_dir = [];
                    num_files = 0;
                    uialert(app.BioFace3DUIFigure, 'No file(s) to process.', 'Error', 'Icon', 'error');
                    app.TextArea.Value = strcat("   >>");
                    drawnow;
                    return;
                end
            else
                try 
                    % Generate a list of files to landmark
                    nombreArchivo = 'files_to_process.txt';
                    fid = fopen(strcat(app.output_path,filesep,nombreArchivo), 'w');
              
                    % Write paths
                    count = 0;
                    for i = 1:numel(app.Tree.CheckedNodes)
                        if exist(strcat(app.input_path,filesep,app.Tree.CheckedNodes(i).Text), 'file') == 2
                            fprintf(fid, '%s\n', strcat(app.input_path,filesep,app.Tree.CheckedNodes(i).Text));
                            count = count + 1;
                        end
                    end

                    num_files = count;
                    
                    % close file
                    fclose(fid);
                    files_dir = strcat(app.output_path,filesep,nombreArchivo);
                    app.TextArea.Value = strcat("   >>");
                    drawnow;
                catch
                    files_dir = [];
                    num_files = 0;
                    uialert(app.BioFace3DUIFigure, 'Unable to generate list of files.', 'Error', 'Icon', 'error');
                    app.TextArea.Value = strcat("   >>");
                    drawnow;
                    return;
                end
            end

        end
    
        function node = findNodeByText(app, tree, textToFind)
            % Find a node in the tree by its text
            rootNode = tree.Children;
            node = findNode(app, rootNode, textToFind);
        end
        
        function node = findNode(app, parentNode, textToFind)
            % Recursive function to find a node by its text
            node = [];
            for i = 1:length(parentNode.Children)
                childNode = parentNode.Children(i);
                if strcmp(childNode.Text, textToFind)
                    node = childNode;
                    return;
                else
                    node = findNode(app, childNode, textToFind);
                    if ~isempty(node)
                        return;
                    end
                end
            end
        end
        
        function selectedFiles = getSelectedFiles(app, tree, parentNode)
            % Get the selected files in the tree from a parent node
            selectedFiles = {};
            for i = 1:length(parentNode.Children)
                childNode = parentNode.Children(i);
                if childNode.isSelected && ~childNode.hasChildren
                    selectedFiles{end + 1} = childNode.Text;
                end
            end
        end

        function chargePaths(app)
           try
                % Create the full file path to JSON
                fullFilePath = fullfile(pwd,'_config.json');
                % Read JSON from the file
                jsonStr = fileread(fullFilePath);
                % Convert JSON to a MATLAB structure
                jsonData = jsondecode(jsonStr);   
                % Access the values from the structure
                app.enviroment_path = jsonData.VirtualEnv.path;

                %Check correct filesep
                file_separator = filesep;
                path_checked = strrep(app.enviroment_path, '/', file_separator);
                path_checked = strrep(path_checked, '\', file_separator);
                path_checked = strrep(path_checked, file_separator, [file_separator, file_separator]);
                app.enviroment_path = path_checked;
                app.installation_path = jsonData.Installation.path;

                % %Check correct filesep
                % file_separator = filesep;
                % path_checked = strrep(app.enviroment_path, '/', file_separator);
                % path_checked = strrep(path_checked, '\', file_separator);
                % path_checked = strrep(path_checked, file_separator, [file_separator, file_separator]);
                % app.enviroment_path = path_checked;

                %Check correct filesep
                file_separator = filesep;
                path_checked = strrep(app.installation_path, '/', file_separator);
                path_checked = strrep(path_checked, '\', file_separator);
                path_checked = strrep(path_checked, file_separator, [file_separator, file_separator]);
                app.installation_path = path_checked;
            catch
                uialert(app.BioFace3DUIFigure, 'Unable to read JSON config file. Close the app.', 'Error', 'Icon', 'error');
                return;
           end
        end

        %% PROCESSING FUNCTIONS
           
        % Individual processing
        function processingOne(app, files_dir)
            app.Loading.Visible = "on";
            app.LoadingLogo.Visible = "on";
            app.TextArea.Value = strcat("   >> Processing one file.");
            drawnow;

            % Init GUI
            app.STEP1Lamp.Color = '#e6e6e6';
            app.STEP2Lamp.Color = '#e6e6e6';
            app.STEP3Lamp.Color = '#e6e6e6';
            app.STEP4Lamp.Color = '#e6e6e6';
            app.STEP1Lamp.Enable = "off";
            app.STEP2Lamp.Enable = "off";
            app.STEP3Lamp.Enable = "off";
            app.STEP4Lamp.Enable = "off";
            app.ContinueButton.Visible = "off";
            app.EndButton.Visible = "off";
            app.PanelThresholdFace.Enable = "off";
            app.PanelThreshold.Enable = "off";

            % Create temporal files dir
            [~, app.processingOne_filename, ~] = fileparts(files_dir);
            app.path_tmp_dir = strcat(app.output_path,filesep,'_tmp',filesep,app.processingOne_filename);
            if ~exist(app.path_tmp_dir, 'dir')
                mkdir(app.path_tmp_dir);
            end

            % Label file
            app.LabelFile.Text = app.processingOne_filename;

            % STEP1 - Slice enhancemnt
            app.STEP1Lamp.Color = '#002e61';
            app.STEP1Lamp.Enable = "on";
            sliceEnhancement(app, files_dir, app.path_tmp_dir);
            % Check step 1 completed
            path_tmp_dir_step1 = strcat(app.path_tmp_dir,filesep,'mri_enhanced.nii');
            if isfile(path_tmp_dir_step1) == 1
                app.STEP1Lamp.Color = "green";
                app.TextArea.Value = strcat("   >> STEP 1: Completed.");
                drawnow;

                % STEP 2  Head reconstruction
                app.STEP2Lamp.Color = '#002e61';
                app.STEP2Lamp.Enable = "on";
                threshold = getThreshold(app, path_tmp_dir_step1, app.path_tmp_dir);
                if ~isempty(threshold)
                    headReconstruction(app, path_tmp_dir_step1, app.path_tmp_dir);

                    % Breakpoint 1
                    if strcmp(app.SwitchStep2.Value,'Break')
                        app.STEP2Lamp.Color = "#ffff11";
                        app.PanelThreshold.Enable = "on";
                        app.ContinueButton.Visible = "on";
                        app.Loading.Visible = "off";
                        app.LoadingLogo.Visible = "off";
                        app.TextArea.Value = strcat("   >> Program stoped at breackpoint.");
                        drawnow;
                        return;
                    end

                    processingOne_fromStep2(app);

                    % Remove tmp dir
                    if ~app.SavetemporalfilesCheckBox.Value
                        out_temp = strcat(app.output_path,filesep,'_tmp');
                        if exist(out_temp, 'dir')
                            rmdir(out_temp, 's');
                        end
                    end
                    return;

                else
                    uialert(app.BioFace3DUIFigure, 'Problem in Step 2. No threshold computed', 'Error', 'Icon', 'error');
                    app.STEP2Lamp.Color = "red";
                    app.Loading.Visible = "off";
                    app.LoadingLogo.Visible = "off";
                    % Remove tmp file
                    if ~app.SavetemporalfilesCheckBox.Value
                        if isfolder(app.path_tmp_dir)
                            rmdir(app.path_tmp_dir, 's');
                        end
                    end
                    return;
                end

            else
                uialert(app.BioFace3DUIFigure, 'Problem in Step 1.', 'Error', 'Icon', 'error');
                app.Loading.Visible = "off";
                app.LoadingLogo.Visible = "off";
                app.STEP1Lamp.Color = "red";
                % Remove tmp file
                if ~app.SavetemporalfilesCheckBox.Value
                    if isfolder(app.path_tmp_dir)
                        rmdir(app.path_tmp_dir, 's');
                    end
                end
                return;
            end
        end

        function processingOne_fromStep2(app)
            app.STEP2Lamp.Color = "green";
            app.Loading.Visible = "on";
            app.LoadingLogo.Visible = "on";
            app.TextArea.Value = strcat("   >> STEP 2: Completed.");
            drawnow;
    
            % STEP 3 - Head processing (Center, clean & alignment)
            path_tmp_dir_step2 = strcat(app.path_tmp_dir,filesep,'head_reconstruction.ply');
            if isfile(path_tmp_dir_step2) == 1
                app.STEP3Lamp.Color = '#002e61';
                app.STEP3Lamp.Enable = "on";
                % Head processing
                headProcessing(app, path_tmp_dir_step2, app.path_tmp_dir);
                path_tmp_dir_step3 = strcat(app.path_tmp_dir,filesep,'head_centered.ply');
                if isfile(path_tmp_dir_step3) == 1
    
                    % STEP 3: Head alignment
                    faceAlignment(app, path_tmp_dir_step3, app.path_tmp_dir)
                    % STEP 3: Empty head
                    path_tmp_dir_step3 = strcat(app.path_tmp_dir,filesep,'head_aligned.ply');
                    emptyHead(app, path_tmp_dir_step3);
    
                    % STEP 4: Face extraction
                    path_tmp_dir_step4 = strcat(app.path_tmp_dir,filesep,'head_empty.ply');
                    if isfile(path_tmp_dir_step4) == 1
                        app.STEP3Lamp.Color = "green";
                        app.STEP4Lamp.Color = '#002e61';
                        app.STEP4Lamp.Enable = "on";
    
                        % Compute thresholds face
                        [th_y, th_z] = computeThresholdFace(app);
                        app.ThresholdYSpinner.Value = th_y;
                        app.ThresholdZSpinner.Value = th_z;
    
                        % Check breakpoint
                        if strcmp(app.SwitchStep4.Value,'Break')
                            app.STEP4Lamp.Color = "#ffff11";
                            app.PanelThresholdFace.Enable = "on";
                            app.EndButton.Visible = "on";
                            app.Loading.Visible = "off";
                            app.LoadingLogo.Visible = "off";
                            return;
                        end

                        processingOne_fromStep4(app);
   
                    else
                        uialert(app.BioFace3DUIFigure, 'Problem in Step 3.', 'Error', 'Icon', 'error');
                        app.STEP3Lamp.Color = "red";
                        app.Loading.Visible = "off";
                        app.LoadingLogo.Visible = "off";
                        % Remove tmp file
                        if ~app.SavetemporalfilesCheckBox.Value
                            if isfolder(app.path_tmp_dir)
                                rmdir(app.path_tmp_dir, 's');
                            end
                        end
                        return;
                    end
    
                else
                    uialert(app.BioFace3DUIFigure, 'Problem in Step 3.', 'Error', 'Icon', 'error');
                    app.Loading.Visible = "off";
                    app.LoadingLogo.Visible = "off";
                    app.STEP3Lamp.Color = "red";
                    % Remove tmp file
                    if ~app.SavetemporalfilesCheckBox.Value
                        if isfolder(app.path_tmp_dir)
                            rmdir(app.path_tmp_dir, 's');
                        end
                    end
                    return;
                end
            else
                uialert(app.BioFace3DUIFigure, 'Problem in Step 2. No head reconstrcuted', 'Error', 'Icon', 'error');
                app.STEP2Lamp.Color = "red";
                app.Loading.Visible = "off";
                app.LoadingLogo.Visible = "off";
                % Remove tmp file
                if ~app.SavetemporalfilesCheckBox.Value
                    if isfolder(app.path_tmp_dir)
                        rmdir(app.path_tmp_dir, 's');
                    end
                end
                return;
            end
        end

        function processingOne_fromStep4(app)
            app.Loading.Visible = "on";
            app.LoadingLogo.Visible = "on";

            % Face extraction
            path_tmp_dir_step4 = strcat(app.path_tmp_dir,filesep,'head_empty.ply');
            faceExtraction(app, path_tmp_dir_step4);

            path_tmp_dir_step4 = strcat(app.path_tmp_dir,filesep,'face.ply');
            if isfile(path_tmp_dir_step4) == 1
                % Save file
                app.STEP4Lamp.Color = "green";
                copyfile(path_tmp_dir_step4,strcat(app.output_path,filesep,app.processingOne_filename,'.ply'));

                % Save snapshot
                if strcmp(app.save_snapshot,'Yes') || strcmp(app.save_snapshot,'yes')
                    app.TextArea.Value = strcat("   >> Saving snapshot.");
                    drawnow;
                
                    snap_dir = strcat(app.output_path,filesep,'_snapshots',filesep,app.processingOne_filename,'.png');
                    try
                
                       if ~exist(strcat(app.output_path,filesep,'_snapshots'), 'dir')
                            mkdir(strcat(app.output_path,filesep,'_snapshots'));
                       end
                       
                        % Python call
                        python_file = strcat(pwd,filesep,'mvcnn',filesep,'getProjection.py');
                        system_command = sprintf('"%s" "%s" "%s" "%s"', ...
                               app.enviroment_path, python_file, path_tmp_dir_step4, snap_dir);
                        system(system_command);
                    catch
                        app.TextArea.Value = strcat("   >> Error generating snapshot.");
                        drawnow;
                    end
                    
                    app.TextArea.Value = strcat("   >>");
                    drawnow;

                end

                % Remove tmp file
                if ~app.SavetemporalfilesCheckBox.Value
                    if isfolder(app.path_tmp_dir)
                        rmdir(app.path_tmp_dir, 's');
                    end
                end
                
                app.Loading.Visible = "off";
                app.LoadingLogo.Visible = "off";
                uialert(app.BioFace3DUIFigure, strcat('Process finished succesfully.'), 'Success', 'Icon', 'success');
            else
                uialert(app.BioFace3DUIFigure, 'Problem in Step 4.', 'Error', 'Icon', 'error');
                app.STEP4Lamp.Color = "red";
                % Remove tmp file
                if ~app.SavetemporalfilesCheckBox.Value
                    if isfolder(app.path_tmp_dir)
                        rmdir(app.path_tmp_dir, 's');
                    end
                end
                app.Loading.Visible = "off";
                app.LoadingLogo.Visible = "off";
                return;
            end
        end

        % % Batch processing
        function processingList(app, files_dir, numFiles)

            % Error file
            outputErrorFilePath = strcat(app.output_path,filesep,'files_not_processed.txt');
            fidError = fopen(outputErrorFilePath, 'w');

            app.FileListLabel.Text = strcat('Files: 1/',num2str(numFiles));
            app.PanelProcessingList.Visible = "on";
            app.TimeLabel.Text = "Calculating remaining time...";

            % Process
            try
                files_processed = 1;
                fid = fopen(files_dir, 'r');
                % For each line in files_dir
                while ~feof(fid)
                    % Stop status
                    if ~app.StopButton.Value
                        startTime = tic;
                        % Read line
                        currentLine = fgetl(fid);
                        [~, basename, ~] = fileparts(currentLine);
                        app.FileListLabel.Text = strcat(num2str(files_processed),'/',num2str(numFiles)," - ","File: ",basename);
                        drawnow;
                        files_processed = files_processed + 1;
                        
                        %Process file
                        try
                            % Create temporal files dir
                            app.path_tmp_dir = strcat(app.output_path,filesep,'_tmp',filesep,basename);
                            if ~exist(app.path_tmp_dir, 'dir')
                                mkdir(app.path_tmp_dir);
                            end
                            
                            %STEP 1
                            sliceEnhancement(app, currentLine, app.path_tmp_dir);
                            path_tmp_dir_step1 = strcat(app.path_tmp_dir,filesep,'mri_enhanced.nii');
                            %STEP 2
                            getThreshold(app, path_tmp_dir_step1, app.path_tmp_dir);
                            headReconstruction(app, path_tmp_dir_step1, app.path_tmp_dir);
                            %STEP 3
                            path_tmp_dir_step2 = strcat(app.path_tmp_dir,filesep,'head_reconstruction.ply');
                            headProcessing(app, path_tmp_dir_step2, app.path_tmp_dir);
                            path_tmp_dir_step3 = strcat(app.path_tmp_dir,filesep,'head_centered.ply');
                            if strcmp(app.batch_alignment,'Yes') || strcmp(app.batch_alignment,'yes')
                                faceAlignment(app, path_tmp_dir_step3, app.path_tmp_dir)
                            else
                                copyfile(path_tmp_dir_step3,strcat(app.path_tmp_dir,filesep,'head_aligned.ply'));
                            end
                            path_tmp_dir_step3 = strcat(app.path_tmp_dir,filesep,'head_aligned.ply');
                            emptyHead(app, path_tmp_dir_step3);
                            % STEP 4
                            [th_y, th_z] = computeThresholdFace(app);
                            app.ThresholdYSpinner.Value = th_y;
                            app.ThresholdZSpinner.Value = th_z;
                            path_tmp_dir_step4 = strcat(app.path_tmp_dir,filesep,'head_empty.ply');
                            faceExtraction(app, path_tmp_dir_step4);
                            path_tmp_dir_step4 = strcat(app.path_tmp_dir,filesep,'face.ply');
                            copyfile(path_tmp_dir_step4,strcat(app.output_path,filesep,basename,'.ply'));

                            if strcmp(app.save_snapshot,'Yes') || strcmp(app.save_snapshot,'yes')
                                app.TextArea.Value = strcat("   >> Saving snapshot.");
                                drawnow;

                                snap_dir = strcat(app.output_path,filesep,'_snapshots',filesep,basename,'.png');
                                try

                                   if ~exist(strcat(app.output_path,filesep,'_snapshots'), 'dir')
                                        mkdir(strcat(app.output_path,filesep,'_snapshots'));
                                   end
                                   
                                    % Python call
                                    python_file = strcat(pwd,filesep,'mvcnn',filesep,'getProjection.py');
                                    system_command = sprintf('"%s" "%s" "%s" "%s"', ...
                                           app.enviroment_path, python_file, path_tmp_dir_step4, snap_dir);
                                    system(system_command);
                                catch
                                    app.TextArea.Value = strcat("   >> Error generating snapshot.");
                                    drawnow;
                                end
                                    app.TextArea.Value = strcat("   >>");
                                    drawnow;
                            end

                            % Remove temporal dir
                            if ~app.SavetemporalfilesCheckBox.Value
                                if exist(app.path_tmp_dir, 'dir')
                                    rmdir(app.path_tmp_dir, 's');
                                end
                            end
                            
    
                            elapsedTime = round(toc(startTime)/60*(numFiles-files_processed+1));
                            if elapsedTime > 1
                                app.TimeLabel.Text = strcat("About ",num2str(elapsedTime)," minutes left");
                            else
                                app.TimeLabel.Text = strcat("About less than 1 minute left");
                            end
                            drawnow;    
                        catch
                           fprintf(fidError, 'Error in file: %s\n', currentLine);
                           % Remove temporal dir
                            if ~app.SavetemporalfilesCheckBox.Value
                                if exist(app.path_tmp_dir, 'dir')
                                    rmdir(app.path_tmp_dir, 's');
                                end
                            end
                        end
                    else
                        app.PanelProcessingList.Visible = "off";
                        app.TimeLabel.Text = "Calculating remaining time...";
                        return;
                    end
                end
            catch
                app.PanelProcessingList.Visible = "off";
                uialert(app.BioFace3DUIFigure, 'Error processing list of files.', 'Error', 'Icon', 'error');
                return;
            end
            % Remove tmp dir
            if ~app.SavetemporalfilesCheckBox.Value
                out_temp = strcat(app.output_path,filesep,'_tmp');
                if exist(out_temp, 'dir')
                    rmdir(out_temp, 's');
                end
            end

            uialert(app.BioFace3DUIFigure, strcat('Process finished. Check output path for results.'), 'Success', 'Icon', 'success');
            app.PanelProcessingList.Visible = "off";
        end

        %S1 - Slice enhancement
        function sliceEnhancement(app, file_dir, path_out)
            app.TextArea.Value = strcat("   >> STEP 1: MRI processing.");
            drawnow;

            python_file = strcat(pwd,filesep,'python',filesep,'sliceEnhancement.py');
            reference_nii = strcat(pwd,filesep,'resources',filesep,'IXI_template.nii');
            system_command = sprintf('"%s" "%s" "%s" "%s" "%s"', ...
                   app.enviroment_path, python_file, file_dir, path_out, reference_nii);
            system(system_command);

            app.TextArea.Value = strcat("   >> ");
            drawnow;
        end

        %S2 - Head reconstruction
        function threshold = getThreshold(app, path_file, path_out)
            app.TextArea.Value = strcat("   >> STEP 2: Threshold prediction.");
            drawnow;
            try
                % Python call
                python_file = strcat(pwd,filesep,'python',filesep,'thresholdPrediction.py');
                threshold_model = strcat(pwd,filesep,'python',filesep,'threshold_model.joblib');
                system_command = sprintf('"%s" "%s" "%s" "%s" "%s"', ...
                       app.enviroment_path, python_file, path_file, path_out, threshold_model);
                system(system_command, '-echo');
                
                mat_file = load(strcat(path_out,filesep,'threshold.mat'));
                threshold = mat_file.prediction;
                app.ThresholdSpinner.Value  = threshold;
            catch
                threshold = [];
            end

            app.TextArea.Value = strcat("   >>");
            drawnow;
        end
        
        function headReconstruction(app, path_file, path_out)
            app.TextArea.Value = strcat("   >> STEP 2: Head reconstruction.");
            drawnow;
            try
                % Python call
                python_file = strcat(pwd,filesep,'python',filesep,'headReconstruction.py');
                system_command = sprintf('"%s" "%s" "%s" "%s" %f', ...
                       app.enviroment_path, python_file, path_file, path_out, app.ThresholdSpinner.Value);
                system(system_command);
            catch
                return;
            end

            app.TextArea.Value = strcat("   >>");
            drawnow;
        end

        % S3 - Head processing
        function headProcessing(app, path_tmp_dir_step2, path_tmp_dir)
            app.TextArea.Value = strcat("   >> STEP 3: Head processing.");
            drawnow;

            try
                % Head cleaning
                % Python call
                python_file = strcat(pwd,filesep,'python',filesep,'headCleaning.py');
                system_command = sprintf('"%s" "%s" "%s" "%s"', ...
                       app.enviroment_path, python_file, path_tmp_dir_step2, path_tmp_dir);
                system(system_command);
    
                % Head centering
                pc_clean = pcread(strcat(path_tmp_dir,filesep,'head_cleaned.ply'));
                % Compute mesh center
                ctr_x = -(pc_clean.XLimits(2) + pc_clean.XLimits(1))/2;
                ctr_y = -(pc_clean.YLimits(2) + pc_clean.YLimits(1))/2;
                ctr_z = -(pc_clean.ZLimits(2) + pc_clean.ZLimits(1))/2;
                % Python call
                python_file = strcat(pwd,filesep,'python',filesep,'headCentering.py');
                system_command = sprintf('"%s" "%s" "%s" "%s" %f %f %f', ...
                       app.enviroment_path, python_file, strcat(path_tmp_dir,filesep,'head_cleaned.ply'), path_tmp_dir, ctr_x, ctr_y, ctr_z);
                system(system_command);
            catch
                app.TextArea.Value = strcat("   >> ");
                drawnow;
                return;
            end

            app.TextArea.Value = strcat("   >> ");
            drawnow;
        end

        % Update model path to JSON
        function updateJSONmodel(app)
             try
                % Read content
                path = strcat(pwd,filesep,'mvcnn',filesep,'__configs',filesep,'LYHM_5Landmarks_25views',filesep,'config.json');
                fid = fopen(path, 'r');
                texto = fread(fid, inf, '*char').';
                fclose(fid);
                
                % Find "model_pth_or_url"
                indiceInicio = strfind(texto, '"model_pth_or_url": "');
                
                % Find URL's end
                indiceFin = strfind(texto(indiceInicio+20:end), '"');
                indiceFin = indiceInicio + 20 + indiceFin(2);
                
                % New path
                nuevoPath = strcat(app.installation_path,filesep,filesep,'mvcnn',...
                    filesep,filesep,'__configs',filesep,filesep,'LYHM_5Landmarks_25views',filesep,filesep,'model_best.pth');
                
                % Reemplaza la URL actual con el nuevo path
                textoActualizado = [texto(1:indiceInicio+20), nuevoPath, texto(indiceFin-1:end)];
                
                % Save JSON file
                fid = fopen(path, 'w');
                fprintf(fid, '%s', textoActualizado);
                fclose(fid);
            catch
                uialert(app.BioFace3DUIFigure, 'Unable to update JSON parameters', 'Error', 'Icon', 'error');
                app.TextArea.Value = strcat("   >>");
                drawnow;
            end
        end

        % S3 - Head alignment
        function faceAlignment(app, path_tmp_dir_step3, ~)
            app.TextArea.Value = strcat("   >> STEP 3: Head alignment.");
            drawnow;

            % Read reference control points
            points1 = dlmread(strcat(pwd,filesep,'resources',filesep,'reference_controlPoints.txt'));
            matchedPoints1 = points1([46 21 29 47 53],:);
            
            % Read input mesh
            [vertices_mov,faces_mov] = plyRead(path_tmp_dir_step3,1);
            ptCloudTformed = pointCloud(vertices_mov);

            % Python call - Predict landmarks
            updateJSONmodel(app);
            path_model = strcat(pwd,filesep,'mvcnn',filesep,'__configs',filesep,'LYHM_5Landmarks_25views',filesep,'config.json');
            predict_file = strcat(pwd,filesep,'mvcnn',filesep,'predict.py');
            system_command = sprintf('"%s" "%s" -c "%s" -n "%s" -mr "%d" -pn "%d" -pt "%d" -o "%s" -of "%s" -ms "%s"', ...
                    app.enviroment_path, predict_file, path_model, strcat(app.path_tmp_dir,filesep,'head_centered.ply'), 1000, 1, 1, app.path_tmp_dir, ...
                    'txt', 'False');
            system(system_command);
            
            % Check if prediction has been done
            if isfile(strcat(app.path_tmp_dir,filesep,'head_centered.txt'))
                % Read matchedpoints2
                points2 = dlmread(strcat(app.path_tmp_dir,filesep,'head_centered.txt'));
                pc_points2 = pointCloud(points2);
                matchedPoints2 = points2;
        
                %% ALIGNMENT
                % Estimate transform amtrix
                [tformEst,~] = estimateGeometricTransform3D(matchedPoints1, ...
                matchedPoints2,'similarity','MaxDistance',20); 
        
                % Apply trnasformation
                ptCloudOut = pctransform(ptCloudTformed,invert(tformEst));
                pc_points2_out = pctransform(pc_points2,invert(tformEst));
        
                % Save data
                plyWrite(ptCloudOut.Location(:,:),faces_mov,strcat(app.path_tmp_dir,filesep,'head_aligned.ply'));
                writematrix(pc_points2_out.Location(:,:),strcat(app.path_tmp_dir,filesep,'head_aligned.txt'),'Delimiter',' ')

                app.TextArea.Value = strcat("   >>");
                drawnow;
            else
                %If file not exists, final alignment is cancelled
                app.TextArea.Value = strcat("   >> STEP 3: Problem. No fine alignment performed");
                drawnow;
                copyfile(strcat(app.path_tmp_dir,filesep,'head_centered.ply'),strcat(app.path_tmp_dir,filesep,'head_aligned.ply'));
            end

        end
        
        %S3 - Empty head
        function emptyHead(app, path_tmp_dir_step3)
            app.TextArea.Value = strcat("   >> STEP 3: Empty head.");
            drawnow;
            
            python_file = strcat(pwd,filesep,'python',filesep,'emptyHead.py');
            system_command = sprintf('"%s" "%s" "%s" "%s" %f', ...
                   app.enviroment_path, python_file, path_tmp_dir_step3, app.path_tmp_dir);
            system(system_command);

            app.TextArea.Value = strcat("   >>");
            drawnow;
        end

        % S4 - Compute thresholds
        function [th_y, th_z] = computeThresholdFace(app)
            app.TextArea.Value = strcat("   >> STEP 4: Computing thresholds.");
            drawnow;

            pc = pcread(strcat(app.path_tmp_dir,filesep,'head_aligned.ply'));
            limit_y = pc.YLimits(1);

            if isfile(strcat(app.path_tmp_dir,filesep,'head_centered.txt')) == 1
                controlPoints = dlmread(strcat(app.path_tmp_dir,filesep,'head_aligned.txt'));
                %Set threshold
                cp_comisura = -controlPoints(5,2);
                th_y = max(-cp_comisura-60,limit_y);
                th_z = 40;
            else
                th_y = -limit_y;
                th_z = 40;
            end

            app.TextArea.Value = strcat("   >> ");
            drawnow;
        end

        % S4 - Face Extraction
        function faceExtraction(app, path_file)
            app.TextArea.Value = strcat("   >> STEP 4: Extracting face.");
            drawnow;

            if strcmp(app.smooth_remesh,'Yes') || strcmp(app.smooth_remesh,'yes')
                smooth = 'True';
            else
                smooth = 'False';
            end

            try
               % Python call
               python_file = strcat(pwd,filesep,'python',filesep,'faceExtraction.py');
               system_command = sprintf('"%s" "%s" "%s" %f %f "%s" "%s"', ...
                      app.enviroment_path, python_file, path_file, app.ThresholdZSpinner.Value, ...
                      app.ThresholdYSpinner.Value, strcat(app.path_tmp_dir,filesep,'face.ply'), smooth);
               system(system_command);
            catch
               app.TextArea.Value = strcat("   >> STEP 4: Problem extracting face.");
               drawnow;
            end

            app.TextArea.Value = strcat("   >>");
            drawnow;
        end

        %%
        function showFilePLY(app, file)
                app.PanelLoadingPLY.Visible = "on";
                app.TextArea.Value = strcat("   >> Close mesh window to follow.");
                drawnow;
                try
                    predict_file = strcat(pwd,filesep,'mvcnn',filesep,'fileVisualization.py');
                    system_command = sprintf('"%s" "%s" "%s"', ...
                        app.enviroment_path, predict_file, file);
                    system(system_command);
                catch
                    uialert(app.BioFace3DUIFigure, 'Unable to show file.', 'Error', 'Icon', 'error');
                    app.PanelLoadingPLY.Visible = "off";
                end
                app.PanelLoadingPLY.Visible = "off";
                app.TextArea.Value = strcat("   >> ");
                drawnow;
        end

        function updateTreeProcessing(app)
            % Get the list of files in the directory
            ext = {'*.ply'};
            extensions = cellfun(@(x)dir(fullfile(app.output_path,x)),ext,'UniformOutput',false);
            files = vertcat(extensions{:});

            if numel(files) > 0
               
                % Display valid files in a Tree (replace 'uitree1' with the actual Tree component)
                app.ProcessedNode.Text = 'Processed';
                for i = 1:numel(files)
                    fileNode = uitreenode(app.ProcessedNode,'Text', string(files(i).name), 'NodeData', []);
                    fileNode.Icon = fullfile(fileparts(mfilename('fullpath')), 'resources', 'eye_ico.png');
                end
                app.TreeProcessed.Enable = "on";
                expand(app.TreeProcessed);
            end

            drawnow;
        end

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, path)
            app.BioFace3DUIFigure.Icon = fullfile(pwd, 'resources', 'icon.png');
            app.Loading_2.ImageSource = fullfile(pwd, 'gui', 'resources', 'loading_ico.gif');
            app.LoadingLogo_2.ImageSource = fullfile(pwd, 'resources', 'icon.png');
            app.Image_4.ImageSource = fullfile(pwd, 'gui', 'resources', 'loading_ico.gif');
            app.ImageLogo_3.ImageSource = fullfile(pwd, 'resources', 'icon.png');
            app.ImageLogo_2.ImageSource = fullfile(pwd, 'resources', 'icon.png');
            app.Image_2.ImageSource = fullfile(pwd, 'gui', 'resources', 'loading_ico.gif');
            app.ImageLogo.ImageSource = fullfile(pwd, 'resources', 'icon.png');
            app.Image_3.ImageSource = fullfile(pwd, 'gui', 'resources', 'loading_ico.gif');
            app.ProcessingButton.Icon = fullfile(pwd, 'gui', 'resources', 'play_ico.png');
            app.SettingsButton.Icon = fullfile(pwd, 'gui', 'resources', 'set_ico.png');
            app.Loading.ImageSource = fullfile(pwd, 'gui', 'resources', 'loading_ico.gif');
            app.LoadingLogo.ImageSource = fullfile(pwd, 'resources', 'icon.png');
            drawnow;
            
            app.Loading.Visible = "on";
            app.LoadingLogo.Visible = "on";
            
            app.PanelLoading.Visible = "on";
            app.TextArea.Value = strcat("   >> Loading module settings.");
            drawnow;

            % Charge parameters
            % app.session_path = "C:\Users\alvaro.heredia\OneDrive - La Salle\DOCTORADO\02_Desarrollo\07_BioFace3D\_session_Process.json"; %Cambiar
            app.bioface_gui = caller;
            app.session_path = path;
            chargePaths(app);
            readDataSession(app, app.session_path);

            % Delete children of PathNode
            if ~isempty(app.InputNode.Children)
                delete(app.InputNode.Children);
                app.InputNode.Children = [];
            end
            ListFilesPushed(app);

            % Update processed panel tree
            if ~isempty(app.ProcessedNode.Children)
                delete(app.ProcessedNode.Children);
                app.ProcessedNode.Children = [];
            end
            updateTreeProcessing(app);

            app.TextArea.Value = strcat("   >>");
            drawnow;
            app.PanelLoading.Visible = "off";

            app.Loading.Visible = "off";
            app.LoadingLogo.Visible = "off";
        end

        % Menu selected function: NewsessionMenu
        function NewsessionMenuSelected(app, event)
            answer = uiconfirm(app.BioFace3DUIFigure, ['Do you want to close the current session (', app.session_name, ') and start a new one?'], ...
                'Confirmation', 'Options', {'Yes', 'Cancel'}, 'DefaultOption', 'Yes');
            if strcmp(answer,'Yes')
                app.bioface_gui = BioFace3D();
                delete(app)
            end
        end

        % Menu selected function: SettingsMenu
        function SettingsMenuSelected(app, event)
            app.Loading.Visible = "on";
            app.LoadingLogo.Visible = "on";
            
            app.TextArea.Value = strcat("   >> Settings displayed in a new window.");
            drawnow;
             
            app.settingsProcessingGUI = settingsProcessing(app,app.session_path);
            waitfor(app.settingsProcessingGUI);

            %Comming back to settings
            chargePaths(app);
            readDataSession(app, app.session_path);
            % Delete children of PathNode
            if ~isempty(app.InputNode.Children)
                delete(app.InputNode.Children);
                app.InputNode.Children = [];
            end
            ListFilesPushed(app);

            % Update processed panel tree
            if ~isempty(app.ProcessedNode.Children)
                delete(app.ProcessedNode.Children);
                app.ProcessedNode.Children = [];
            end
            updateTreeProcessing(app);

            app.TextArea.Value = strcat("   >> ");
            drawnow;

            app.Loading.Visible = "off";
            app.LoadingLogo.Visible = "off";
        end

        % Button pushed function: SettingsButton
        function SettingsButtonPushed(app, event)
            app.Loading.Visible = "on";
            app.LoadingLogo.Visible = "on";
            
            app.TextArea.Value = strcat("   >> Settings displayed in a new window.");
            drawnow;
             
            app.settingsProcessingGUI = settingsProcessing(app,app.session_path);
            waitfor(app.settingsProcessingGUI);

            %Comming back to settings
            chargePaths(app);
            readDataSession(app, app.session_path);
            % Delete children of PathNode
            if ~isempty(app.InputNode.Children)
                delete(app.InputNode.Children);
                app.InputNode.Children = [];
            end
            ListFilesPushed(app);

             % Update processed panel tree
            if ~isempty(app.ProcessedNode.Children)
                delete(app.ProcessedNode.Children);
                app.ProcessedNode.Children = [];
            end
            updateTreeProcessing(app);

            app.TextArea.Value = strcat("   >> ");
            drawnow;

            app.Loading.Visible = "off";
            app.LoadingLogo.Visible = "off";
        end

        % Button pushed function: ChangefolderButton
        function ChangefolderButtonPushed(app, event)
            app.Loading.Visible = "on";
            app.LoadingLogo.Visible = "on";
            
            app.TextArea.Value = strcat("   >> Changing input folder.");
            drawnow;
            
            % Open file explorer
            selectedPath = uigetdir('Select input path');

            % Check valid dir
            if selectedPath ~= 0
                % Chaneg path
                app.input_path = selectedPath;
                % Delete children of PathNode
                if ~isempty(app.InputNode.Children)
                    delete(app.InputNode.Children);
                    app.InputNode.Children = [];
                end
                % Update new files
                updateDataSession(app, app.session_path);
                ListFilesPushed(app);
            else
                uialert(app.BioFace3DUIFigure, 'Invalid path', 'Error', 'Icon', 'error');
            end

            app.TextArea.Value = strcat("   >> ");
            drawnow;

            app.Loading.Visible = "off";
            app.LoadingLogo.Visible = "off";
        end

        % Button pushed function: ProcessingButton
        function ProcessingButtonPushed(app, event)
            %Check data selected
            if ~isempty(app.Tree.CheckedNodes)
                [files_dir, num_files] = getFilesDir(app); % Check individual o batch processing
                app.TreeProcessed.Enable = "off";
                if ~isempty(files_dir)
                    if num_files == 1
                        processingOne(app, files_dir);
                    elseif num_files > 1
                        processingList(app, files_dir, num_files);
                    end

                    % Update processed panel tree
                    if ~isempty(app.ProcessedNode.Children)
                        delete(app.ProcessedNode.Children);
                        app.ProcessedNode.Children = [];
                    end
                    updateTreeProcessing(app);
                end
                app.TreeProcessed.Enable = "on";
            else
                uialert(app.BioFace3DUIFigure, 'Select file(s) to process.', 'Error', 'Icon', 'error');
            end
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.ContinueButton.Visible = "off";
            % Continue break porint from step2
            processingOne_fromStep2(app);
            
            % Update processed panel tree
            if ~isempty(app.ProcessedNode.Children)
                delete(app.ProcessedNode.Children);
                app.ProcessedNode.Children = [];
            end
            updateTreeProcessing(app);
        end

        % Button pushed function: SeemeshButton
        function SeemeshButtonPushed(app, event)
            if isfile(strcat(app.path_tmp_dir,filesep,'mri_enhanced.nii')) == 1
                path_tmp_dir_step1 = strcat(app.path_tmp_dir,filesep,'mri_enhanced.nii');
                headReconstruction(app, path_tmp_dir_step1, app.path_tmp_dir);
                showFilePLY(app, strcat(app.path_tmp_dir,filesep,'head_reconstruction.ply'));
            else
                uialert(app.BioFace3DUIFigure, 'Unable to render file.', 'Error', 'Icon', 'error');
            end
        end

        % Button pushed function: SeemeshFaceButton
        function SeemeshFaceButtonPushed(app, event)
            if isfile(strcat(app.path_tmp_dir,filesep,'head_empty.ply')) == 1
                % Face extraction
                faceExtraction(app, strcat(app.path_tmp_dir,filesep,'head_empty.ply'));
                showFilePLY(app, strcat(app.path_tmp_dir,filesep,'face.ply'));
            else
                uialert(app.BioFace3DUIFigure, 'Unable to render file.', 'Error', 'Icon', 'error');
            end
        end

        % Double-clicked callback: TreeProcessed
        function TreeProcessedDoubleClicked(app, event)
            node = event.InteractionInformation.Node;
            % Show file
            %app.DisplayingmeshinanewwindowLabel.Text = strcat(node.Text," landmarks displayed in new window. Close to continue.");
            app.TextArea.Value = strcat("   >> Displaying mesh in new window.");
            drawnow;
            %panelLoading(app, 'on');
            showFilePLY(app, strcat(app.output_path,filesep,node.Text));
            %panelLoading(app, 'off');
            app.TextArea.Value = strcat("   >> ");
            drawnow;
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            app.EndButton.Visible = "off";
            % Continue breakpoint from step4
            processingOne_fromStep4(app);

            % Update processed panel tree
            if ~isempty(app.ProcessedNode.Children)
                delete(app.ProcessedNode.Children);
                app.ProcessedNode.Children = [];
            end
            updateTreeProcessing(app);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BioFace3DUIFigure = uifigure('Visible', 'off');
            app.BioFace3DUIFigure.Name = 'BioFace3D';
            app.SessionMenu = uimenu(app.BioFace3DUIFigure);
            app.SessionMenu.Text = 'Session';
            app.NewsessionMenu = uimenu(app.SessionMenu);
            app.NewsessionMenu.MenuSelectedFcn = createCallbackFcn(app, @NewsessionMenuSelected, true);
            app.NewsessionMenu.Accelerator = 'N';
            app.NewsessionMenu.Text = 'New session';
            app.SettingsMenu = uimenu(app.SessionMenu);
            app.SettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SettingsMenuSelected, true);
            app.SettingsMenu.Text = 'Settings';
            app.ExitMenu = uimenu(app.SessionMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.Toolbar = uitoolbar(app.BioFace3DUIFigure);
            app.Tree = uitree(app.BioFace3DUIFigure, 'checkbox');
            app.InputNode = uitreenode(app.Tree);
            app.InputNode.Text = 'Input';
            app.InputfilesLabel = uilabel(app.BioFace3DUIFigure);
            app.InputfilesLabel.Text = 'Input files';
            app.ChangefolderButton = uibutton(app.BioFace3DUIFigure, 'push');
            app.ChangefolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChangefolderButtonPushed, true);
            app.ChangefolderButton.Text = 'Change folder';
            app.TextArea = uitextarea(app.BioFace3DUIFigure);
            app.TextArea.Editable = 'off';
            app.StatusLabel = uilabel(app.BioFace3DUIFigure);
            app.StatusLabel.Text = 'Status';
            app.TreeProcessed = uitree(app.BioFace3DUIFigure);
            app.TreeProcessed.Enable = 'off';
            app.TreeProcessed.DoubleClickedFcn = createCallbackFcn(app, @TreeProcessedDoubleClicked, true);
            app.ProcessedNode = uitreenode(app.TreeProcessed);
            app.ProcessedNode.Text = 'Processed';
            app.ProcessingButton = uibutton(app.BioFace3DUIFigure, 'push');
            app.ProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessingButtonPushed, true);
            app.ProcessingButton.Text = 'Processing';
            app.SettingsButton = uibutton(app.BioFace3DUIFigure, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.SettingsButton.Text = 'Settings';
            app.Label_4 = uilabel(app.BioFace3DUIFigure);
            app.Label_4.Text = '-----------------------------------------------------------------------------------------------------------------';
            app.OutputfacialmeshLabel = uilabel(app.BioFace3DUIFigure);
            app.OutputfacialmeshLabel.Text = 'Output facial mesh';
            app.filesNumberLabel = uilabel(app.BioFace3DUIFigure);
            app.filesNumberLabel.Text = '0 of 245 files to landmark';
            app.SessionNameLabel = uilabel(app.BioFace3DUIFigure);
            app.SessionNameLabel.Text = 'SESSION NAME';
            app.dataSessionLabel = uilabel(app.BioFace3DUIFigure);
            app.dataSessionLabel.Text = 'From NIfTI to facial mesh';
            app.IndividualProcessingPanel = uipanel(app.BioFace3DUIFigure);
            app.ProcessingLabel = uilabel(app.IndividualProcessingPanel);
            app.ProcessingLabel.Text = 'Processing';
            app.Step1Panel = uipanel(app.IndividualProcessingPanel);
            app.STEP1LampLabel = uilabel(app.Step1Panel);
            app.STEP1LampLabel.Text = 'STEP 1';
            app.STEP1Lamp = uilamp(app.Step1Panel);
            app.STEP1Lamp.Enable = 'off';
            app.SliceprocessingenhancementLabel = uilabel(app.Step1Panel);
            app.SliceprocessingenhancementLabel.Text = 'Slice processing & enhancement';
            app.Step2Panel = uipanel(app.IndividualProcessingPanel);
            app.STEP2LampLabel = uilabel(app.Step2Panel);
            app.STEP2LampLabel.Text = 'STEP 2';
            app.STEP2Lamp = uilamp(app.Step2Panel);
            app.STEP2Lamp.Enable = 'off';
            app.HeadreconstructionLabel = uilabel(app.Step2Panel);
            app.HeadreconstructionLabel.Text = 'Head reconstruction';
            app.SwitchStep2 = uiswitch(app.Step2Panel, 'slider');
            app.SwitchStep2.Items = {'', 'Break'};
            app.SwitchStep2.Value = '';
            app.PanelThreshold = uipanel(app.Step2Panel);
            app.PanelThreshold.Enable = 'off';
            app.ThresholdSpinnerLabel = uilabel(app.PanelThreshold);
            app.ThresholdSpinnerLabel.Text = 'Threshold';
            app.ThresholdSpinner = uispinner(app.PanelThreshold);
            app.ThresholdSpinner.Limits = [0 Inf];
            app.SeemeshButton = uibutton(app.PanelThreshold, 'push');
            app.SeemeshButton.ButtonPushedFcn = createCallbackFcn(app, @SeemeshButtonPushed, true);
            app.SeemeshButton.Text = 'See mesh';
            app.Step3Panel = uipanel(app.IndividualProcessingPanel);
            app.STEP3LampLabel = uilabel(app.Step3Panel);
            app.STEP3LampLabel.Text = 'STEP 3';
            app.STEP3Lamp = uilamp(app.Step3Panel);
            app.STEP3Lamp.Enable = 'off';
            app.HeadprocessingLabel = uilabel(app.Step3Panel);
            app.HeadprocessingLabel.Text = 'Head processing';
            app.Step4Panel = uipanel(app.IndividualProcessingPanel);
            app.STEP4LampLabel = uilabel(app.Step4Panel);
            app.STEP4LampLabel.Text = 'STEP 4';
            app.STEP4Lamp = uilamp(app.Step4Panel);
            app.STEP4Lamp.Enable = 'off';
            app.FaceextractionLabel = uilabel(app.Step4Panel);
            app.FaceextractionLabel.Text = 'Face extraction';
            app.SwitchStep4 = uiswitch(app.Step4Panel, 'slider');
            app.SwitchStep4.Items = {'', 'Break'};
            app.SwitchStep4.Value = '';
            app.PanelThresholdFace = uipanel(app.Step4Panel);
            app.PanelThresholdFace.Enable = 'off';
            app.ThresholdYSpinner_2Label = uilabel(app.PanelThresholdFace);
            app.ThresholdYSpinner_2Label.Text = 'Threshold Y';
            app.ThresholdYSpinner = uispinner(app.PanelThresholdFace);
            app.ThresholdZSpinner_2Label = uilabel(app.PanelThresholdFace);
            app.ThresholdZSpinner_2Label.Text = 'Threshold Z';
            app.ThresholdZSpinner = uispinner(app.PanelThresholdFace);
            app.SeemeshFaceButton = uibutton(app.PanelThresholdFace, 'push');
            app.SeemeshFaceButton.ButtonPushedFcn = createCallbackFcn(app, @SeemeshFaceButtonPushed, true);
            app.SeemeshFaceButton.Text = 'See mesh';
            app.SavetemporalfilesCheckBox = uicheckbox(app.IndividualProcessingPanel);
            app.SavetemporalfilesCheckBox.Text = 'Save temporal files';
            app.ContinueButton = uibutton(app.IndividualProcessingPanel, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Visible = 'off';
            app.ContinueButton.Text = '>> Continue';
            app.EndButton = uibutton(app.IndividualProcessingPanel, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Visible = 'off';
            app.EndButton.Text = '>> End';
            app.LabelFile = uilabel(app.IndividualProcessingPanel);
            app.LabelFile.Text = '';
            app.Loading = uiimage(app.IndividualProcessingPanel);
            app.Loading.Visible = 'off';
            app.LoadingLogo = uiimage(app.IndividualProcessingPanel);
            app.LoadingLogo.Visible = 'off';
            app.Label_2 = uilabel(app.BioFace3DUIFigure);
            app.Label_2.Text = '----------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------';
            app.PanelLoading = uipanel(app.BioFace3DUIFigure);
            app.PanelLoading.Visible = 'off';
            app.Image_2 = uiimage(app.PanelLoading);
            app.LoadingLabel = uilabel(app.PanelLoading);
            app.LoadingLabel.Text = '';
            app.ImageLogo = uiimage(app.PanelLoading);
            app.PanelLoading_2 = uipanel(app.PanelLoading);
            app.PanelLoading_2.Visible = 'off';
            app.Image_3 = uiimage(app.PanelLoading_2);
            app.LoadingLabel_2 = uilabel(app.PanelLoading_2);
            app.LoadingLabel_2.Text = '';
            app.ImageLogo_2 = uiimage(app.PanelLoading_2);
            app.PanelLoadingPLY = uipanel(app.BioFace3DUIFigure);
            app.PanelLoadingPLY.Visible = 'off';
            app.Image_4 = uiimage(app.PanelLoadingPLY);
            app.DisplayingmeshinanewwindowLabel = uilabel(app.PanelLoadingPLY);
            app.DisplayingmeshinanewwindowLabel.Text = 'Displaying mesh in a new window';
            app.ImageLogo_3 = uiimage(app.PanelLoadingPLY);
            app.PanelProcessingList = uipanel(app.BioFace3DUIFigure);
            app.PanelProcessingList.Visible = 'off';
            app.Loading_2 = uiimage(app.PanelProcessingList);
            app.LoadingLogo_2 = uiimage(app.PanelProcessingList);
            app.ProcessingfilesLabel = uilabel(app.PanelProcessingList);
            app.ProcessingfilesLabel.Text = 'Processing files';
            app.FileListLabel = uilabel(app.PanelProcessingList);
            app.FileListLabel.Text = '';
            app.TimeLabel = uilabel(app.PanelProcessingList);
            app.TimeLabel.Text = 'Calculating remaining time...';
            app.StopButton = uibutton(app.PanelProcessingList, 'state');
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.BioFace3DUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MRI_processing(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BioFace3DUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.BioFace3DUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BioFace3DUIFigure)
        end
    end
end