classdef newSession_Biomarkers < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BioFace3DUIFigure          matlab.ui.Figure
        ImageLogo                  matlab.ui.control.Image
        Image                      matlab.ui.control.Image
        ModelDropDown              matlab.ui.control.DropDown
        ModelDropDownLabel         matlab.ui.control.Label
        CreatefileButton_2         matlab.ui.control.Button
        CreatefileButton           matlab.ui.control.Button
        PathButton                 matlab.ui.control.Button
        PathEditField              matlab.ui.control.EditField
        NameEditField_3Label_2     matlab.ui.control.Label
        OpenfileButton_2           matlab.ui.control.Button
        OpenfileButton             matlab.ui.control.Button
        NewBiomarkersSessionLabel  matlab.ui.control.Label
        NameG2EditField            matlab.ui.control.EditField
        NameEditField_2Label       matlab.ui.control.Label
        Group2Label                matlab.ui.control.Label
        Group1Label                matlab.ui.control.Label
        NameG1EditField            matlab.ui.control.EditField
        NameEditFieldLabel         matlab.ui.control.Label
        StartButton_2              matlab.ui.control.Button
        CancelButton               matlab.ui.control.Button
        SessionnameEditField       matlab.ui.control.EditField
        NameEditField_3Label       matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        FileG2EditField            matlab.ui.control.EditField
        FileEditField_2Label       matlab.ui.control.Label
        FileG1EditField            matlab.ui.control.EditField
        FileEditFieldLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        main_gui
        biomark_gui
    end
    
    methods (Access = private)

        function isExcel = isExcelFile(~,path)
            % Check if the file extension is .xls or .xlsx
            [~, ~, extension] = fileparts(path);
            isExcel = strcmpi(extension, '.xls') || strcmpi(extension, '.xlsx');
        end
        
        function valid = checkFields(app)
            % Get values from the fields
            sessionName = app.SessionnameEditField.Value;
            nameG1 = app.NameG1EditField.Value;
            nameG2 = app.NameG2EditField.Value;
            fileG1 = app.FileG1EditField.Value;
            fileG2 = app.FileG2EditField.Value;
            pathOut = app.PathEditField.Value;
            valid = true;
        
            % Check that name fields are not empty
            if isempty(sessionName) || isempty(nameG1) || isempty(nameG2)
                uialert(app.BioFace3DUIFigure, 'Name fields cannot be empty', 'Error', 'Icon', 'error');
                valid = false;
                return;
            end
        
            % Check that file fields contain paths to Excel files
            if ~isExcelFile(app,fileG1) || ~isExcelFile(app,fileG2)
                uialert(app.BioFace3DUIFigure, 'File fields must contain paths to Excel files', 'Error', 'Icon', 'error');
                valid = false;
                return;
            end

            % Check valid output path
            if ~isfolder(pathOut)
                uialert(app.BioFace3DUIFigure, 'Invalid output path', 'Error', 'Icon', 'error');
                valid = false;
                return;
            end
        end

        % Callback for the button that will generate the JSON
        function fullFilePath  = btnGenerateJSONPushed(app)

            % Session data
            sessionData = struct('name', app.SessionnameEditField.Value, 'output_path', app.PathEditField.Value);

            % Model
            switch(app.ModelDropDown.Value)
                case '20 Landmarks'
                    num_land = 20;
                case '21 Landmarks'
                    num_land = 21;
            end
            
            % Create a structure with the values from the fields
            biomarkersData = struct('num_landmarks', num_land, 'group1_name', app.NameG1EditField.Value, ...
                'group1_file', app.FileG1EditField.Value, 'group2_name', app.NameG2EditField.Value, ...
                'group2_file', app.FileG2EditField.Value);
    
            % Create a structure for the entire JSON
            jsonData.session = sessionData;
            jsonData.biomarkers = biomarkersData;

            % Convert the structure to JSON format
            jsonStr = jsonencode(jsonData,'PrettyPrint',true);
    
            % Create the full file path
            fullFilePath = fullfile(app.PathEditField.Value, ['_session_' app.SessionnameEditField.Value '_biom.json']);
    
            % Save the JSON to the specified file
            try
                fid = fopen(fullFilePath, 'w');
                fwrite(fid, jsonStr, 'char');
                fclose(fid);
            catch
                uialert(app.BioFace3DUIFigure, 'Error saving JSON to file', 'Error', 'Icon', 'error');
                fullFilePath = '';
            end
            
        end

        function [output_file] = createLandmarksTable(app, directoryPath, outputExcelPath)
            
            try
                % List .txt files in the directory
                txtFiles = dir(fullfile(directoryPath, '*.txt'));
            
                % Initialize variables
                file = cell(numel(txtFiles), 1);
                %landmarksData = cell(numel(txtFiles), 1);
            
                % Read each file and store data
                for i = 1:numel(txtFiles)
                    % Get file name and full path
                    fullFilePath = fullfile(directoryPath, txtFiles(i).name);
            
                    % Get basename
                    file{i} = txtFiles(i).name(1:end-4);
            
                    % Read file content and store in cell
                    landmarks = readLandmarkFile(app, fullFilePath);

                    % Reshape the matrix to a column vector
                    landmarksData(i,:) = reshape(landmarks', [], 1)';
                end
            
                % Create the table
                land_table = array2table(landmarksData);
                base_table = table(file);
                landmarkTable = horzcat(base_table, land_table);
            
                % Save the table to an Excel file
                output_file = strcat(outputExcelPath,filesep,'metadata_landmarks.xlsx');
                writetable(landmarkTable, output_file);
            catch
                output_file = [];
                uialert(app.BioFace3DUIFigure, 'Error creating Excel metadata file.', 'Error', 'Icon', 'error');
            end
        end
 
        function landmarksData = readLandmarkFile(~, filePath)
            % This function reads the content of the landmark file
            fdet = fopen(filePath, 'r');
        
            % Read data
            data = textscan(fdet, '%f %f %f');
            fclose(fdet);
        
            % Store data in a matrix
            landmarksData = cell2mat(data);
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.BioFace3DUIFigure.Icon = fullfile(pwd, 'resources', 'icon.png');
            app.PathButton.Icon = fullfile(pwd, 'gui', 'resources', 'ico_folder.png');
            app.Image.ImageSource = fullfile(pwd, 'resources', 'loading_ico.gif');
            app.ImageLogo.ImageSource = fullfile(pwd, 'resources', 'icon.png');
            drawnow;

            app.main_gui = caller;
            app.SessionnameEditField.Value = app.main_gui.session_name;
        end

        % Close request function: BioFace3DUIFigure
        function BioFace3DUIFigureCloseRequest(app, event)
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            %Close app
            delete(app)
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            if checkFields(app)
                path = btnGenerateJSONPushed(app);
                if ~isempty(path)
                    % Open biomark gui
                    app.biomark_gui = Biomarkers(app, path);
                    app.main_gui.close_main_gui = true;
                    delete(app)
                end
            end
        end

        % Button pushed function: OpenfileButton
        function OpenfileButtonPushed(app, event)
            [file,path] = uigetfile({'*.xlsx';'*.xls'});
             % Check valid dir
            if file ~= 0
                % Chaneg path
                app.FileG1EditField.Value = strcat(path,file);
            else
                uialert(app.BioFace3DUIFigure, 'Invalid path to file.', 'Error', 'Icon', 'error');
            end
        end

        % Button pushed function: OpenfileButton_2
        function OpenfileButton_2Pushed(app, event)
            [file,path] = uigetfile({'*.xlsx';'*.xls'});
             % Check valid dir
            if file ~= 0
                % Chaneg path
                app.FileG2EditField.Value = strcat(path,file);
            else
                uialert(app.BioFace3DUIFigure, 'Invalid path to file.', 'Error', 'Icon', 'error');
            end
        end

        % Button pushed function: PathButton
        function PathButtonPushed(app, event)
            path = uigetdir('Select output path.');
             % Check valid dir
            if path ~= 0
                % Chaneg path
                app.PathEditField.Value = path;
            else
                uialert(app.BioFace3DUIFigure, 'Invalid path.', 'Error', 'Icon', 'error');
            end
        end

        % Button pushed function: CreatefileButton
        function CreatefileButtonPushed(app, event)
            app.ImageLogo.Visible = "on";
            app.Image.Visible = "on";

            path = uigetdir('Select directory of landmarks.');
             % Check valid dir
            if path ~= 0
                % Cretae excel
                output_path = createLandmarksTable(app, path, path);
                if ~isempty(output_path)
                    app.FileG1EditField.Value = output_path;
                end
            else
                uialert(app.BioFace3DUIFigure, 'Invalid path.', 'Error', 'Icon', 'error');
            end

            app.ImageLogo.Visible = "off";
            app.Image.Visible = "off";
        end

        % Button pushed function: CreatefileButton_2
        function CreatefileButton_2Pushed(app, event)
            app.ImageLogo.Visible = "on";
            app.Image.Visible = "on";
            
            path = uigetdir('Select directory of landmarks.');
             % Check valid dir
            if path ~= 0
                % Cretae excel
                output_path = createLandmarksTable(app, path, path);
                if ~isempty(output_path)
                    app.FileG2EditField.Value = output_path;
                end
            else
                uialert(app.BioFace3DUIFigure, 'Invalid path.', 'Error', 'Icon', 'error');
            end

            app.ImageLogo.Visible = "off";
            app.Image.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BioFace3DUIFigure = uifigure('Visible', 'off');
            app.BioFace3DUIFigure.Name = 'BioFace3D';
            app.BioFace3DUIFigure.CloseRequestFcn = createCallbackFcn(app, @BioFace3DUIFigureCloseRequest, true);
            app.FileEditFieldLabel = uilabel(app.BioFace3DUIFigure);
            app.FileEditFieldLabel.Text = 'File';
            app.FileG1EditField = uieditfield(app.BioFace3DUIFigure, 'text');
            app.FileEditField_2Label = uilabel(app.BioFace3DUIFigure);
            app.FileEditField_2Label.Text = 'File';
            app.FileG2EditField = uieditfield(app.BioFace3DUIFigure, 'text');
            app.Label = uilabel(app.BioFace3DUIFigure);
            app.Label.Text = '------------------------------------------------------------------------------------------------------------------';
            app.Label_2 = uilabel(app.BioFace3DUIFigure);
            app.Label_2.Text = '-------------------------------------------------------------------------------------------------------------------';
            app.NameEditField_3Label = uilabel(app.BioFace3DUIFigure);
            app.NameEditField_3Label.Text = 'Name';
            app.SessionnameEditField = uieditfield(app.BioFace3DUIFigure, 'text');
            app.CancelButton = uibutton(app.BioFace3DUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.StartButton_2 = uibutton(app.BioFace3DUIFigure, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.NameEditFieldLabel = uilabel(app.BioFace3DUIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameG1EditField = uieditfield(app.BioFace3DUIFigure, 'text');
            app.NameG1EditField.Value = 'Group1';
            app.Group1Label = uilabel(app.BioFace3DUIFigure);
            app.Group1Label.Text = 'Group 1';
            app.Group2Label = uilabel(app.BioFace3DUIFigure);
            app.Group2Label.Text = 'Group 2';
            app.NameEditField_2Label = uilabel(app.BioFace3DUIFigure);
            app.NameEditField_2Label.Text = 'Name';
            app.NameG2EditField = uieditfield(app.BioFace3DUIFigure, 'text');
            app.NameG2EditField.Value = 'Group2';
            app.NewBiomarkersSessionLabel = uilabel(app.BioFace3DUIFigure);
            app.NewBiomarkersSessionLabel.Text = 'New Biomarkers Session';
            app.OpenfileButton = uibutton(app.BioFace3DUIFigure, 'push');
            app.OpenfileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenfileButtonPushed, true);
            app.OpenfileButton.Text = 'Open file';
            app.OpenfileButton_2 = uibutton(app.BioFace3DUIFigure, 'push');
            app.OpenfileButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenfileButton_2Pushed, true);
            app.OpenfileButton_2.Text = 'Open file';
            app.NameEditField_3Label_2 = uilabel(app.BioFace3DUIFigure);
            app.NameEditField_3Label_2.Text = 'Path';
            app.PathEditField = uieditfield(app.BioFace3DUIFigure, 'text');
            app.PathButton = uibutton(app.BioFace3DUIFigure, 'push');
            app.PathButton.ButtonPushedFcn = createCallbackFcn(app, @PathButtonPushed, true);
            app.PathButton.Text = '';
            app.CreatefileButton = uibutton(app.BioFace3DUIFigure, 'push');
            app.CreatefileButton.ButtonPushedFcn = createCallbackFcn(app, @CreatefileButtonPushed, true);
            app.CreatefileButton.Text = 'Create file';
            app.CreatefileButton_2 = uibutton(app.BioFace3DUIFigure, 'push');
            app.CreatefileButton_2.ButtonPushedFcn = createCallbackFcn(app, @CreatefileButton_2Pushed, true);
            app.CreatefileButton_2.Text = 'Create file';
            app.ModelDropDownLabel = uilabel(app.BioFace3DUIFigure);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.BioFace3DUIFigure);
            app.ModelDropDown.Items = {'20 Landmarks', '21 Landmarks'};
            app.ModelDropDown.Value = '20 Landmarks';
            app.Image = uiimage(app.BioFace3DUIFigure);
            app.Image.Visible = 'off';
            app.ImageLogo = uiimage(app.BioFace3DUIFigure);
            app.ImageLogo.Visible = 'off';
            % Show the figure after all components are created
            app.BioFace3DUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newSession_Biomarkers(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BioFace3DUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.BioFace3DUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BioFace3DUIFigure)
        end
    end
end