classdef newSession_MRI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BioFace3DUIFigure             matlab.ui.Figure
        SessionnameEditField          matlab.ui.control.EditField
        NameEditFieldLabel            matlab.ui.control.Label
        PathOutEditField              matlab.ui.control.EditField
        OutputpathEditFieldLabel      matlab.ui.control.Label
        PathInEditField               matlab.ui.control.EditField
        InputpathEditFieldLabel       matlab.ui.control.Label
        PathButton_2                  matlab.ui.control.Button
        PathButton                    matlab.ui.control.Button
        NewMRIProcessingSessionLabel  matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        Label                         matlab.ui.control.Label
    end

    
    properties (Access = private)
        init_gui
        path_input
        path_output
        mri_gui
    end
    
    properties (Access = public)
        input_path
        output_path
        session_name
        input_data
        output_data
    end
    
    methods (Access = private)
        
         function valid = checkFields(app)
            % Get values from the fields
            sessionName = app.SessionnameEditField.Value;
            pathOut = app.PathOutEditField.Value;
            pathIn = app.PathInEditField.Value;
            valid = true;
        
            % Check that name fields are not empty
            if isempty(sessionName) || isempty(pathOut) || isempty(pathIn)
                uialert(app.BioFace3DUIFigure, 'Name fields cannot be empty', 'Error', 'Icon', 'error');
                valid = false;
                return;
            end
        
           % Check valid input path
            if ~isfolder(pathIn)
                uialert(app.BioFace3DUIFigure, 'Invalid input path', 'Error', 'Icon', 'error');
                valid = false;
                return;
            end

            % Check valid output path
            if ~isfolder(pathOut)
                uialert(app.BioFace3DUIFigure, 'Invalid output path', 'Error', 'Icon', 'error');
                valid = false;
                return;
            end
        end

        % Callback for the button that will generate the JSON
        function fullFilePath  = btnGenerateJSONPushed(app)

            % Session data
            sessionData = struct('name', app.SessionnameEditField.Value,'input_path',app.PathInEditField.Value,'output_path', app.PathOutEditField.Value);
            
            % Create a structure with the values from the fields
            batch_processing = 'Yes';
            save_snapshot = 'No';
            smooth_remesh = 'Yes';
            mri_data = struct('batch_alignment', batch_processing, 'save_snapshot', save_snapshot, 'smooth_remesh', smooth_remesh);
    
            % Create a structure for the entire JSON
            jsonData.session = sessionData;
            jsonData.mri_processing = mri_data;

            % Convert the structure to JSON format
            jsonStr = jsonencode(jsonData,'PrettyPrint',true);
    
            % Create the full file path
            fullFilePath = fullfile(app.PathOutEditField.Value, ['_session_' app.SessionnameEditField.Value '_MRIProcess.json']);
    
            % Save the JSON to the specified file
            try
                fid = fopen(fullFilePath, 'w');
                fwrite(fid, jsonStr, 'char');
                fclose(fid);
            catch
                uialert(app.BioFace3DUIFigure, 'Error saving JSON to file', 'Error', 'Icon', 'error');
                fullFilePath = '';
            end
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.BioFace3DUIFigure.Icon = fullfile(pwd, 'resources', 'icon.png');
            app.PathButton.Icon = fullfile(pwd, 'gui', 'resources', 'ico_folder.png');
            app.PathButton_2.Icon = fullfile(pwd, 'gui', 'resources', 'ico_folder.png');
            drawnow;

            app.init_gui = caller;
            app.SessionnameEditField.Value = app.init_gui.session_name;
        end

        % Close request function: BioFace3DUIFigure
        function BioFace3DUIFigureCloseRequest(app, event)
             % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed2(app, event)
            delete(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed2(app, event)
             if checkFields(app)
                path = btnGenerateJSONPushed(app);
                if ~isempty(path)
                    % Open landmarking gui
                    app.mri_gui = MRI_processing(app, path);
                    delete(app)
                end
            end
        end

        % Button pushed function: PathButton_2
        function PathButton_2Pushed(app, event)
            path = uigetdir('Select output path.');
             % Check valid dir
            if path ~= 0
                % Chaneg path
                app.PathOutEditField.Value = path;
            else
                uialert(app.BioFace3DUIFigure, 'Invalid path.', 'Error', 'Icon', 'error');
            end
        end

        % Button pushed function: PathButton
        function PathButtonPushed(app, event)
            path = uigetdir('Select input path.');
            % Check valid dir
            if path ~= 0
                % Chaneg path
                app.PathInEditField.Value = path;
            else
                uialert(app.BioFace3DUIFigure, 'Invalid path.', 'Error', 'Icon', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BioFace3DUIFigure = uifigure('Visible', 'off');
            app.BioFace3DUIFigure.Name = 'BioFace3D';
            app.BioFace3DUIFigure.CloseRequestFcn = createCallbackFcn(app, @BioFace3DUIFigureCloseRequest, true);
            app.Label = uilabel(app.BioFace3DUIFigure);
            app.Label.Text = '-------------------------------------------------------------------------------------------------------------------';
            app.CancelButton = uibutton(app.BioFace3DUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed2, true);
            app.CancelButton.Text = 'Cancel';
            app.StartButton = uibutton(app.BioFace3DUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed2, true);
            app.StartButton.Text = 'Start';
            app.NewMRIProcessingSessionLabel = uilabel(app.BioFace3DUIFigure);
            app.NewMRIProcessingSessionLabel.Text = 'New MRI Processing Session';
            app.PathButton = uibutton(app.BioFace3DUIFigure, 'push');
            app.PathButton.ButtonPushedFcn = createCallbackFcn(app, @PathButtonPushed, true);
            app.PathButton.Text = '';
            app.PathButton_2 = uibutton(app.BioFace3DUIFigure, 'push');
            app.PathButton_2.ButtonPushedFcn = createCallbackFcn(app, @PathButton_2Pushed, true);
            app.PathButton_2.Text = '';
            app.InputpathEditFieldLabel = uilabel(app.BioFace3DUIFigure);
            app.InputpathEditFieldLabel.Text = 'Input path';
            app.PathInEditField = uieditfield(app.BioFace3DUIFigure, 'text');
            app.OutputpathEditFieldLabel = uilabel(app.BioFace3DUIFigure);
            app.OutputpathEditFieldLabel.Text = 'Output path';
            app.PathOutEditField = uieditfield(app.BioFace3DUIFigure, 'text');
            app.NameEditFieldLabel = uilabel(app.BioFace3DUIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.SessionnameEditField = uieditfield(app.BioFace3DUIFigure, 'text');
            % Show the figure after all components are created
            app.BioFace3DUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newSession_MRI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BioFace3DUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BioFace3DUIFigure)
        end
    end
end