classdef settingsProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SettingsUIFigure     matlab.ui.Figure
        ModuleSettingsLabel  matlab.ui.control.Label
        SaveButton           matlab.ui.control.Button
        CancelButton         matlab.ui.control.Button
        UITable              matlab.ui.control.Table
    end

    
    properties (Access = public)
        processing_gui
    end
    
    properties (Access = private)
        session_path
        session_name
        input_path
        output_path
        batch_alignment
        save_snapshot
        smooth_remesh
    end
    
    methods (Access = private)
        % Show data
        function readDataSession(app, path)
  
            try
                % Read JSON from the file
                jsonStr = fileread(path);
    
                % Convert JSON to a MATLAB structure
                jsonData = jsondecode(jsonStr);
    
                % Access the values from the structure
                app.session_name = jsonData.session.name;
                app.input_path = jsonData.session.input_path;
                app.output_path = jsonData.session.output_path;
                app.batch_alignment = jsonData.mri_processing.batch_alignment;
                app.save_snapshot = jsonData.mri_processing.save_snapshot;
                app.smooth_remesh = jsonData.mri_processing.smooth_remesh;

                
                % Plot data
                Parameters = {'Session name';'Input path';'Output path';'Batch alignment'; 'Save snapshot'; 'Smooth remesh'};
                Values = {app.session_name;app.input_path;app.output_path;app.batch_alignment; app.save_snapshot; app.smooth_remesh};
                t = table(Parameters, Values);
                app.UITable.Data = t;

            catch
                uialert(app.SettingsUIFigure, 'Error reading or displaying settings parameters', 'Error', 'Icon', 'error');
            end
        end

        function updateDataSession(app, path)
            try
                % Read the JSON file and decode it into a MATLAB structure
                jsonData = jsondecode(fileread(path));
                
                % Update or modify fields in the MATLAB structure
                jsonData.session.name = cell2mat(app.UITable.DisplayData{1,2});
                jsonData.session.input_path = cell2mat(app.UITable.DisplayData{2,2});
                jsonData.session.output_path = cell2mat(app.UITable.DisplayData{3,2});
                jsonData.mri_processing.batch_alignment = cell2mat(app.UITable.DisplayData{4,2});
                jsonData.mri_processing.save_snapshot = cell2mat(app.UITable.DisplayData{5,2});
                jsonData.mri_processing.smooth_remesh = cell2mat(app.UITable.DisplayData{6,2});
             
                % Encode the MATLAB structure back into JSON format
                updatedJsonString = jsonencode(jsonData,'PrettyPrint',true);
                
                % Write the updated JSON string back to the file
                fid = fopen(path, 'w');
                if fid > 0
                    fprintf(fid, '%s', updatedJsonString);
                    fclose(fid);
                end
            catch
                uialert(app.SettingsUIFigure, 'Unable to update settings parameters', 'Error', 'Icon', 'error');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, session_path)
            app.SettingsUIFigure.Icon = fullfile(pwd, 'resources', 'icon.png');
       
            app.processing_gui = caller;
            app.session_path = session_path;
            readDataSession(app, app.session_path)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            %Close app
            delete(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Save changes
            updateDataSession(app, app.session_path);
            % Close app
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SettingsUIFigure = uifigure('Visible', 'off');
            app.SettingsUIFigure.Name = 'Settings';
            app.UITable = uitable(app.SettingsUIFigure);
            app.UITable.ColumnName = {'Parameters'; 'Value'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.CancelButton = uibutton(app.SettingsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveButton = uibutton(app.SettingsUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ModuleSettingsLabel = uilabel(app.SettingsUIFigure);
            app.ModuleSettingsLabel.Text = 'Module Settings';
            % Show the figure after all components are created
            app.SettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = settingsProcessing(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SettingsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SettingsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SettingsUIFigure)
        end
    end
end