classdef BioFace3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BioFace3DUIFigure              matlab.ui.Figure
        SelectModuleLabel              matlab.ui.control.Label
        NewSessionButton               matlab.ui.control.Button
        OpenSessionButton              matlab.ui.control.Button
        BeGiNProjectLabel              matlab.ui.control.Label
        SessionnameEditField           matlab.ui.control.EditField
        SessionnameEditFieldLabel      matlab.ui.control.Label
        ExampleLandmarks_BipolarLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        ButtonGroup                    matlab.ui.container.ButtonGroup
        FacialBiomarkersButton         matlab.ui.control.ToggleButton
        DLandmarkingButton             matlab.ui.control.ToggleButton
        MRIHeadProcessingButton        matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        % GUIs
        newSession_mri_gui
        newSession_landmarking_gui
        newSession_biomarkers_gui
        mri_gui
        landmarking_gui
        biomarkers_gui
    end
    
    properties (Access = public)
        session_name
        close_main_gui = false;
    end
    
    methods (Access = private)
        
        function answer = buttonStartNewSessionPushed(app, selectedEvent, sessionName)
            % Muestra un cuadro de diálogo de confirmación
            answer = uiconfirm(app.BioFace3DUIFigure, ['Do you want to start a ', selectedEvent, ' session with name ', sessionName, '?'], ...
                'Confirmation', 'Options', {'Yes', 'Cancel'}, 'DefaultOption', 'Yes');
        end

        function chosenField = checkJSONfilesession(app, path)
            chosenField = '';
            % Read JSON
            try
                jsonData = jsondecode(fileread(path));
            catch
                uialert(app.BioFace3DUIFigure, 'Unable to read JSON file.', 'Error', 'Icon', 'error');
                return;
            end
            
            % Check modules
            fieldsPresent = {'mri_processing', 'landmarking', 'biomarkers'};
            presentFields = intersect(fieldsPresent, fieldnames(jsonData));
            
            if isempty(presentFields)
                uialert(app.BioFace3DUIFigure, 'No module session in JSON file.', 'Error', 'Icon', 'error');
                return;
            end
            
            % If exists multiples modules
            if numel(presentFields) > 1
                chosenField = questdlg(['JSON session file contains multiples modules. Please, ' ...
                    'chose one:'], 'Choose module', presentFields{:}, presentFields{1});
            
                if isempty(chosenField)
                    return;
                end
            else
                % Only one module
                chosenField = presentFields{1};
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Show logo
            app.Image.ImageSource = fullfile(pwd, 'gui', 'resources', 'logo.png');
            app.BioFace3DUIFigure.Icon = fullfile(pwd, 'gui', 'resources', 'icon.png');
            drawnow;

            % Check if the path is already in MATLAB's search path
            newPath = strcat(pwd,filesep,"gui");
            newPath2 = strcat(pwd,filesep,"scripts");
            newPath3 = strcat(pwd,filesep,"python");
            currentPaths = strsplit(path, pathsep);
            if ~any(strcmp(currentPaths, newPath))
                % Add the path if it hasn't been added before
                addpath(newPath);
                addpath(newPath2);
                addpath(newPath3);
            end
        end

        % Button pushed function: NewSessionButton
        function NewSessionButtonPushed(app, event)
            % Check session field
            if isempty(app.SessionnameEditField.Value)
                uialert(app.BioFace3DUIFigure, 'Insert a valid session name', 'Error', 'Icon', 'error');
            else
                answer = buttonStartNewSessionPushed(app, app.ButtonGroup.SelectedObject.Text, app.SessionnameEditField.Value);
                if strcmp(answer,'Yes')
                    app.session_name = app.SessionnameEditField.Value;
                    switch(app.ButtonGroup.SelectedObject.Text)
                        case "MRI & Head Processing"
                            app.newSession_mri_gui = newSession_MRI(app);
                            waitfor(app.newSession_mri_gui);
                            if app.close_main_gui
                                delete(app)
                            end
                        case "3D Landmarking"
                            app.newSession_landmarking_gui = newSession_Landmarking(app);
                        case "Facial Biomarkers"
                            app.newSession_biomarkers_gui = newSession_Biomarkers(app);
                    end
                end
            end
        end

        % Button pushed function: OpenSessionButton
        function OpenSessionButtonPushed(app, event)
            [file,path] = uigetfile('*.json','Select a JSON session file');
             % Check valid dir
            if file ~= 0
                % Check file
                fullFilePath = fullfile(path, file);
                choosenField = checkJSONfilesession(app, fullFilePath);
                if ~isempty(choosenField)
                    switch(choosenField)
                        case 'mri_processing'
                            app.mri_gui = MRI_processing(app, fullFilePath);
                        case 'landmarking'
                            app.landmarking_gui = Landmarking(app, fullFilePath);
                        case 'biomarkers'
                            app.biomarkers_gui = Biomarkers(app, fullFilePath);
                    end
                    %Close app
                    delete(app)
                end
            else
                uialert(app.BioFace3DUIFigure, 'Invalid JSON session file.', 'Error', 'Icon', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BioFace3DUIFigure = uifigure('Visible', 'off');
            app.BioFace3DUIFigure.Name = 'BioFace3D';
            app.ButtonGroup = uibuttongroup(app.BioFace3DUIFigure);
            app.MRIHeadProcessingButton = uitogglebutton(app.ButtonGroup);
            app.MRIHeadProcessingButton.Text = 'MRI & Head Processing';
            app.MRIHeadProcessingButton.Value = true;
            app.DLandmarkingButton = uitogglebutton(app.ButtonGroup);
            app.DLandmarkingButton.Text = '3D Landmarking';
            app.FacialBiomarkersButton = uitogglebutton(app.ButtonGroup);
            app.FacialBiomarkersButton.Text = 'Facial Biomarkers';
            app.Image = uiimage(app.BioFace3DUIFigure);
            app.ExampleLandmarks_BipolarLabel = uilabel(app.BioFace3DUIFigure);
            app.ExampleLandmarks_BipolarLabel.Text = 'Example: Landmarks_Bipolar';
            app.SessionnameEditFieldLabel = uilabel(app.BioFace3DUIFigure);
            app.SessionnameEditFieldLabel.Text = 'Session name';
            app.SessionnameEditField = uieditfield(app.BioFace3DUIFigure, 'text');
            app.BeGiNProjectLabel = uilabel(app.BioFace3DUIFigure);
            app.BeGiNProjectLabel.Text = '© BeGiN Project';
            app.OpenSessionButton = uibutton(app.BioFace3DUIFigure, 'push');
            app.OpenSessionButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSessionButtonPushed, true);
            app.OpenSessionButton.Text = 'Open Session';
            app.NewSessionButton = uibutton(app.BioFace3DUIFigure, 'push');
            app.NewSessionButton.ButtonPushedFcn = createCallbackFcn(app, @NewSessionButtonPushed, true);
            app.NewSessionButton.Text = 'New Session';
            app.SelectModuleLabel = uilabel(app.BioFace3DUIFigure);
            app.SelectModuleLabel.Text = 'Select Module';
            % Show the figure after all components are created
            app.BioFace3DUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BioFace3D

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BioFace3DUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.BioFace3DUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BioFace3DUIFigure)
        end
    end
end