classdef Landmarking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BioFace3DUIFigure            matlab.ui.Figure
        SessionMenu                  matlab.ui.container.Menu
        NewsessionMenu               matlab.ui.container.Menu
        SettingsMenu                 matlab.ui.container.Menu
        ExitMenu                     matlab.ui.container.Menu
        Toolbar                      matlab.ui.container.Toolbar
        PanelLoadingPLY              matlab.ui.container.Panel
        ImageLogo_2                  matlab.ui.control.Image
        DisplayingmeshinanewwindowLabel  matlab.ui.control.Label
        Image_2                      matlab.ui.control.Image
        PanelLoading                 matlab.ui.container.Panel
        LandmarkingfilesLabel        matlab.ui.control.Label
        StopButton                   matlab.ui.control.StateButton
        TimeLabel                    matlab.ui.control.Label
        ImageLogo                    matlab.ui.control.Image
        FileListLabel                matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        dataSessionLabel             matlab.ui.control.Label
        SessionNameLabel             matlab.ui.control.Label
        PredictionsLabel             matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        StatusLabel                  matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        TreePredictions              matlab.ui.container.Tree
        PredictionsNode              matlab.ui.container.TreeNode
        MetadataButtonGroup          matlab.ui.container.ButtonGroup
        NoButton                     matlab.ui.control.RadioButton
        YesButton                    matlab.ui.control.RadioButton
        ExcelmetadataLabel           matlab.ui.control.Label
        OutputformatLabel            matlab.ui.control.Label
        FormatButtonGroup            matlab.ui.container.ButtonGroup
        allButton                    matlab.ui.control.RadioButton
        fcsvButton                   matlab.ui.control.RadioButton
        landmarkAsciiButton          matlab.ui.control.RadioButton
        txtButton                    matlab.ui.control.RadioButton
        MaxTriesSpinner              matlab.ui.control.Spinner
        MaxTriesSpinnerLabel         matlab.ui.control.Label
        MeanPredictionsSpinner       matlab.ui.control.Spinner
        MeanPredictionsSpinnerLabel  matlab.ui.control.Label
        MaxRansacErrorSpinner        matlab.ui.control.Spinner
        MaxRansacErrorSpinnerLabel   matlab.ui.control.Label
        ParametersLabel              matlab.ui.control.Label
        ModelLabel                   matlab.ui.control.Label
        SelectmodelDropDown          matlab.ui.control.DropDown
        SelectmodelDropDownLabel     matlab.ui.control.Label
        SettingsButton               matlab.ui.control.Button
        LandmarkingButton            matlab.ui.control.Button
        filesNumberLabel             matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        ChangefolderButton           matlab.ui.control.Button
        InputfilesLabel              matlab.ui.control.Label
        Tree                         matlab.ui.container.CheckBoxTree
        PathNode                     matlab.ui.container.TreeNode
        Label                        matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Enviroment
        enviroment_path
        %GUI
        settingsLandmarkingGUI
        session_gui
        bioface_gui
    end
    
    properties (Access = private)
        % Variables
        session_name
        output_path
        input_path
        path_session
        models_path_dir
        installation_path
    end
    
    methods (Access = private)
        
        function readDataSession(app, fullFilePath)
            try
                % Read JSON from the file
                jsonStr = fileread(fullFilePath);
    
                % Convert JSON to a MATLAB structure
                jsonData = jsondecode(jsonStr);
    
                % Access the values from the structure
                app.session_name = jsonData.session.name;
                app.input_path = jsonData.session.input_path;
                app.output_path = jsonData.session.output_path;

                % Display values in text labels (replace 'LabelName', 'LabelInputPath', 'LabelOutputPath' with actual labels)
                app.SessionNameLabel.Text = ['Session: ' app.session_name];
    
            catch
                uialert(app.BioFace3DUIFigure, 'Error reading or displaying session values', 'Error', 'Icon', 'error');
            end
        end

        % Callback for the button that reads and displays files in a Tree
        function ListFilesPushed(app, ~)
            % Get a list of all files in the selected directory
            files = dir(fullfile(app.input_path, '*.ply*'));

            % Filter out directories from the list
            files = files(~[files.isdir]);

            % Number of files
            app.filesNumberLabel.Text = strcat(num2str(numel(files)), ' files in folder');

            % Display valid files in a Tree (replace 'uitree1' with the actual Tree component)
            app.PathNode.Text = app.input_path;
            for i = 1:numel(files)
                fileNode = uitreenode(app.PathNode,'Text', files(i).name, 'NodeData', []);
            end
            
        end

        function chargePaths(app)
           try
                % Create the full file path to JSON
                fullFilePath = fullfile(pwd,'_config.json');
                % Read JSON from the file
                jsonStr = fileread(fullFilePath);
                % Convert JSON to a MATLAB structure
                jsonData = jsondecode(jsonStr);   
                % Access the values from the structure
                app.enviroment_path = jsonData.VirtualEnv.path;

                %Check correct filesep
                file_separator = filesep;
                path_checked = strrep(app.enviroment_path, '/', file_separator);
                path_checked = strrep(path_checked, '\', file_separator);
                path_checked = strrep(path_checked, file_separator, [file_separator, file_separator]);
                app.enviroment_path = path_checked;
                app.installation_path = jsonData.Installation.path;

                % %Check correct filesep
                % file_separator = filesep;
                % path_checked = strrep(app.enviroment_path, '/', file_separator);
                % path_checked = strrep(path_checked, '\', file_separator);
                % path_checked = strrep(path_checked, file_separator, [file_separator, file_separator]);
                % app.enviroment_path = path_checked;

                %Check correct filesep
                file_separator = filesep;
                path_checked = strrep(app.installation_path, '/', file_separator);
                path_checked = strrep(path_checked, '\', file_separator);
                path_checked = strrep(path_checked, file_separator, [file_separator, file_separator]);
                app.installation_path = path_checked;
            catch
                uialert(app.BioFace3DUIFigure, 'Unable to read JSON config file. Close the app.', 'Error', 'Icon', 'error');
                return;
           end
        end

        % Charge landmarking models
        function chargeModels(app)
            app.models_path_dir = fullfile(pwd,'mvcnn','__configs');

            % Obtiene la lista de carpetas en el directorio base
            folderList = dir(app.models_path_dir);
            
            % Filtra solo las carpetas (omitiendo archivos)
            folderNames = {folderList([folderList.isdir] & ~ismember({folderList.name}, {'.', '..'})).name};
            
            % Actualiza el DropDown con las carpetas
            app.SelectmodelDropDown.Items = folderNames;
        end

        % Visible panel laoding
        function panelLoading(app, enable)
            app.PanelLoading.Visible = enable;
            if strcat(enable,'on')
                app.TextArea.Value = strcat("   >> Landmarking prediction. This process can take few minutes. Check MATLAB Command Window for more details.");
                drawnow;
            else
                app.TextArea.Value = strcat("   >> Landmarking finished.");
                drawnow;
            end
        end

        % Check files to landmarks
        function [files_dir, numfiles] = getFilesDir(app)
            files_dir = {};
            numfiles = 0;
            if length(app.Tree.CheckedNodes) == 1
                if exist(strcat(app.input_path,filesep,app.Tree.CheckedNodes(1).Text), 'file') == 2
                    files_dir{1,1} = strcat(app.input_path,filesep,app.Tree.CheckedNodes(1).Text);
                    numfiles = 1;
                else
                    uialert(app.BioFace3DUIFigure, 'No file(s) to landmark.', 'Error', 'Icon', 'error');
                    app.TextArea.Value = strcat("   >>");
                    drawnow;
                end
            else
                try 
                    % Write paths
                    count = 1;
                    for i = 1:numel(app.Tree.CheckedNodes)
                        if isfile(strcat(app.input_path,filesep,app.Tree.CheckedNodes(i).Text))
                            files_dir{count,1} = strcat(app.input_path,filesep,app.Tree.CheckedNodes(i).Text);
                            count = count + 1;
                        end
                    end
                    numfiles = count - 1;
                catch
                    uialert(app.BioFace3DUIFigure, 'Unable to generate list of files.', 'Error', 'Icon', 'error');
                    app.TextArea.Value = strcat("   >>");
                    drawnow;
                end
            end

        end
        
        % Get network parameters
        function [path_model, max_ransac, mean_predictions, max_tries, output_format, metadata_save] = getParametersLandmarking(app)
            max_ransac = app.MaxRansacErrorSpinner.Value;
            mean_predictions = app.MeanPredictionsSpinner.Value;
            max_tries = app.MaxTriesSpinner.Value;
            output_format = app.FormatButtonGroup.SelectedObject.Text;

            if strcmp(app.MetadataButtonGroup.SelectedObject.Text,'Yes')
                metadata_save = 'True';
            else
                metadata_save = 'False';
            end

            path_model = strcat(app.models_path_dir,filesep,app.SelectmodelDropDown.Value,filesep,'config.json');
        end

        % Update model path to JSON
        function updateJSONmodel(app,path)
             try
                % Read the JSON file and decode it into a MATLAB structure
                jsonData = jsondecode(fileread(path));

                if isempty(regexp(jsonData.predict.model_pth_or_url, '^https?://', 'once')) % Check if exsits URL
                    
                    % Read content
                    fid = fopen(path, 'r');
                    texto = fread(fid, inf, '*char').';
                    fclose(fid);
                    
                    % Find "model_pth_or_url"
                    indiceInicio = strfind(texto, '"model_pth_or_url": "');
                    
                    % Find URL's end
                    indiceFin = strfind(texto(indiceInicio+20:end), '"');
                    indiceFin = indiceInicio + 20 + indiceFin(2);
                    
                    % New path
                    nuevoPath = strcat(app.installation_path,filesep,filesep,'mvcnn',...
                        filesep,filesep,'__configs',filesep,filesep,app.SelectmodelDropDown.Value,filesep,filesep,'model_best.pth');
                    
                    % Reemplaza la URL actual con el nuevo path
                    textoActualizado = [texto(1:indiceInicio+20), nuevoPath, texto(indiceFin-1:end)];
                    
                    % Save JSON file
                    fid = fopen(path, 'w');
                    fprintf(fid, '%s', textoActualizado);
                    fclose(fid);
                 end
            catch
                uialert(app.BioFace3DUIFigure, 'Unable to update JSON parameters', 'Error', 'Icon', 'error');
                app.TextArea.Value = strcat("   >>");
                drawnow;
            end
        end

        % Landmarking process
        function processLandmarking(app, files_dir, numfiles, path_model, max_ransac, mean_predictions, max_tries, output_format, metadata_save)
            app.TimeLabel.Text = "Calculating remaining time...";
            count_files = 1;
            for i = 1: numfiles
                if ~app.StopButton.Value
                    file = string(files_dir(i,1));
                    [~,basename,~] = fileparts(file);
                    app.FileListLabel.Text = strcat(num2str(count_files),'/',num2str(numfiles)," - ","File: ",basename);
                    drawnow;
                    if isfile(file)
                        startTime = tic;
    
                        landmarkingMVCNN(app, file, path_model, max_ransac, mean_predictions, max_tries, output_format, metadata_save)
                        
                        elapsedTime = round(toc(startTime)/60*(numfiles-count_files+1));
                        if (elapsedTime > 1) && (numfiles > 1)
                            app.TimeLabel.Text = strcat("About ",num2str(elapsedTime)," minutes left");
                        else
                            app.TimeLabel.Text = strcat("About less than 1 minute left");
                        end
                        drawnow; 
                        count_files = count_files + 1;
                    end
                else
                    app.TextArea.Value = strcat("   >>");
                    drawnow;
                    return;
                end
            end

            uialert(app.BioFace3DUIFigure, strcat('Process finished. Check output path for results.'), 'Success', 'Icon', 'success');
            app.TextArea.Value = strcat("   >>");
            drawnow;
        end

        % Landmarking method
        % R. R. Paulsen, K. A. Juhl, T. M. Haspang, T. Hansen, M. Ganz, y G. Einarsson, 
        % «Multi-view consensus CNN for 3D facial landmark placement», vol. 11361, pp. 706-719, 2019. doi: 10.1007/978-3-030-20887-5_44.
        function landmarkingMVCNN(app, file, path_model, max_ransac, mean_predictions, max_tries, output_format, metadata_save)
            try
                predict_file = strcat(pwd,filesep,'mvcnn',filesep,'predict.py');
                system_command = sprintf('"%s" "%s" -c "%s" -n "%s" -mr "%d" -pn "%d" -pt "%d" -o "%s" -of "%s" -ms "%s"', ...
                    app.enviroment_path, predict_file, path_model, file, max_ransac, mean_predictions, max_tries, app.output_path, ...
                    output_format, metadata_save);
                system(system_command);
            catch
                uialert(app.BioFace3DUIFigure, 'Unable to predict landmarks.', 'Error', 'Icon', 'error');
                app.TextArea.Value = strcat("   >>");
                drawnow;
            end
        end

        function updateTreePredictions(app)
            % Get the list of files in the directory
            ext = {'*.txt','*.landmarkAscii','*.fcsv'};
            extensions = cellfun(@(x)dir(fullfile(app.output_path,x)),ext,'UniformOutput',false);
            files = vertcat(extensions{:});

            landmark_files = [];
            if numel(files) > 0
                % Iterate through the files
                for i = 1:numel(files)
                    % Get the file name without extension
                    [~, baseFileName, extension] = fileparts(files(i).name);
                    basename_list{i} = baseFileName;
                    extension_list{i} = extension;
                end

                % Unique files
                [files_unique,ia,~] = unique(basename_list);
                
                for j = 1:numel(files_unique)
                    % Read the content of the file
                    content = fileread(fullfile(app.output_path, files(ia(j)).name));
            
                    if strcmp(extension_list{ia(j)},'.txt')
                        % Check if the content follows the Nx3 structure
                        if ~isempty(regexp(content, '^\s*[-\d\.]+\s*[-\d\.]+\s*[-\d\.]+\s*$', 'once', 'lineanchors'))
                            % Add the file name to the list of landmark files
                            landmark_files{end+1} = basename_list(ia(j));
                        end
                    else
                        landmark_files{end+1} = basename_list(ia(j));
                    end
                end
                
                % Display valid files in a Tree (replace 'uitree1' with the actual Tree component)
                app.PredictionsNode.Text = 'Predictions';
                for i = 1:numel(landmark_files)
                    fileNode = uitreenode(app.PredictionsNode,'Text', string(landmark_files{i}), 'NodeData', []);
                    fileNode.Icon = fullfile(fileparts(mfilename('fullpath')), 'resources', 'eye_ico.png');
                end
                app.TreePredictions.Enable = "on";
                expand(app.TreePredictions);
            end
        end

        % Show 3D mesh + landmarks in python
        function showFilePLY(app, file)
            % Check landmark extension
            path_ply = fullfile(app.input_path,[file,'.ply']);
            path_land = fullfile(app.output_path,[file,'.txt']);

            if ~(exist(path_land, 'file') == 2)
                path_land = fullfile(app.output_path,[file,'.landmarkAscii']);
                if ~(exist(path_land, 'file') == 2)
                    path_land = fullfile(app.output_path,[file,'.fcsv']);
                end
            end

            existPLY = exist(path_ply, 'file') == 2;
            existLand = exist(path_land, 'file') == 2;
            existenciaArchivos = [existPLY, existLand];
            % Plot in python
            if all(existenciaArchivos)
                try
                    predict_file = strcat(pwd,filesep,'mvcnn',filesep,'fileVisualization.py');
                    system_command = sprintf('"%s" "%s" "%s" "%s"', ...
                        app.enviroment_path, predict_file, path_ply, path_land);
                    system(system_command);
                catch
                    uialert(app.BioFace3DUIFigure, 'Unable to predict landmarks.', 'Error', 'Icon', 'error');
                end
            else
                uialert(app.BioFace3DUIFigure, 'Problem reading files.', 'Error', 'Icon', 'error');
            end
        end

        function updateDataSession(app, path)
            try
                % Read the JSON file and decode it into a MATLAB structure
                jsonData = jsondecode(fileread(path));
                
                % Update or modify fields in the MATLAB structure
                jsonData.session.input_path = app.input_path;
                jsonData.session.output_path = app.output_path;
                jsonData.session.name = app.session_name;
                
                % Encode the MATLAB structure back into JSON format
                updatedJsonString = jsonencode(jsonData,'PrettyPrint',true);
                
                % Write the updated JSON string back to the file
                fid = fopen(path, 'w');
                if fid > 0
                    fprintf(fid, '%s', updatedJsonString);
                    fclose(fid);
                end
            catch
                uialert(app.BioFace3DUIFigure, 'Unable to update settings parameters', 'Error', 'Icon', 'error');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, path)
            app.BioFace3DUIFigure.Icon = fullfile(pwd, 'resources', 'icon.png');
            app.LandmarkingButton.Icon = fullfile(pwd, 'resources', 'play_ico.png');
            app.SettingsButton.Icon = fullfile(pwd,'gui','resources', 'set_ico.png');
            app.Image.ImageSource = fullfile(pwd, 'resources', 'loading_ico.gif');
            app.ImageLogo.ImageSource = fullfile(pwd, 'resources', 'icon.png');
            app.Image_2.ImageSource = fullfile(pwd, 'resources', 'loading_ico.gif');
            app.ImageLogo_2.ImageSource = fullfile(pwd, 'resources', 'icon.png');
       
            % Charge settings
            app.TextArea.Value = strcat("   >> Loading module settings.");
            drawnow;
            
            app.session_gui = caller;
            app.path_session = path;
            chargePaths(app);
            chargeModels(app);
            readDataSession(app, app.path_session);
            ListFilesPushed(app);

            % Update panel tree
            if ~isempty(app.PredictionsNode.Children)
                delete(app.PredictionsNode.Children);
                app.PredictionsNode.Children = [];
            end
            updateTreePredictions(app);

            app.TextArea.Value = strcat("   >> ");
            drawnow;
        end

        % Button pushed function: ChangefolderButton
        function ChangefolderButtonPushed(app, event)
            app.TextArea.Value = strcat("   >> Changing input folder.");
            drawnow;
            
            % Open file explorer
            selectedPath = uigetdir('Select input path');

            % Check valid dir
            if selectedPath ~= 0
                % Chaneg path
                app.input_path = selectedPath;
                % Delete children of PathNode
                if ~isempty(app.PathNode.Children)
                    delete(app.PathNode.Children);
                    app.PathNode.Children = [];
                end
                % Update new files
                updateDataSession(app, app.path_session);
                ListFilesPushed(app);
            else
                uialert(app.BioFace3DUIFigure, 'Invalid path', 'Error', 'Icon', 'error');
            end

            app.TextArea.Value = strcat("   >> ");
            drawnow;
        end

        % Button pushed function: SettingsButton
        function SettingsButtonPushed(app, event)
            app.TextArea.Value = strcat("   >> Settings displayed in a new window.");
            drawnow;
             
            app.settingsLandmarkingGUI = settingsLandmarking(app,app.path_session);
            waitfor(app.settingsLandmarkingGUI);

            %Comming back to settings
            chargePaths(app);
            readDataSession(app, app.path_session);
            % Delete children of PathNode
            if ~isempty(app.PathNode.Children)
                delete(app.PathNode.Children);
                app.PathNode.Children = [];
            end
            ListFilesPushed(app);

            app.TextArea.Value = strcat("   >> ");
            drawnow;
        end

        % Button pushed function: LandmarkingButton
        function LandmarkingButtonPushed(app, event)
            %Check data selected
            if ~isempty(app.Tree.CheckedNodes)
                app.FileListLabel.Text = "Processing landmarks";
                panelLoading(app, 'on');
                [files_dir, numfiles] = getFilesDir(app);
                if numfiles > 0
                    [path_model, max_ransac, mean_predictions, max_tries, output_format, metadata_save] = getParametersLandmarking(app);
                    updateJSONmodel(app, path_model);
                    processLandmarking(app, files_dir, numfiles, path_model, max_ransac, mean_predictions, max_tries, output_format, metadata_save);
                    app.TextArea.Value = strcat("   >>");
                end
                % Update panel tree
                if ~isempty(app.PredictionsNode.Children)
                    delete(app.PredictionsNode.Children);
                    app.PredictionsNode.Children = [];
                end
                updateTreePredictions(app);
                panelLoading(app, 'off');
            else
                uialert(app.BioFace3DUIFigure, 'Select file(s) to landmark.', 'Error', 'Icon', 'error');
            end
        end

        % Double-clicked callback: TreePredictions
        function TreePredictionsDoubleClicked(app, event)
            node = event.InteractionInformation.Node;
            % Show file
            app.FileListLabel.Text = strcat(node.Text," landmarks displayed in new window. Close to continue.");
            app.TextArea.Value = strcat("   >> Displaying landmarks in new window.");
            app.PanelLoadingPLY.Visible = "on";
            drawnow;
            showFilePLY(app, node.Text);
            app.PanelLoadingPLY.Visible = "off";
            app.TextArea.Value = strcat("   >> ");
            drawnow;
        end

        % Menu selected function: NewsessionMenu
        function NewsessionMenuSelected(app, event)
            answer = uiconfirm(app.BioFace3DUIFigure, ['Do you want to close the current session (', app.session_name, ') and start a new one?'], ...
                'Confirmation', 'Options', {'Yes', 'Cancel'}, 'DefaultOption', 'Yes');
            if strcmp(answer,'Yes')
                app.bioface_gui = BioFace3D();
                delete(app)
            end
        end

        % Menu selected function: SettingsMenu
        function SettingsMenuSelected(app, event)
            app.TextArea.Value = strcat("   >> Settings displayed in a new window.");
            drawnow;
             
            app.settingsLandmarkingGUI = settingsLandmarking(app,app.path_session);
            waitfor(app.settingsLandmarkingGUI);

            %Comming back to settings
            chargePaths(app);
            readDataSession(app, app.path_session);
            % Delete children of PathNode
            if ~isempty(app.PathNode.Children)
                delete(app.PathNode.Children);
                app.PathNode.Children = [];
            end
            ListFilesPushed(app);

            app.TextArea.Value = strcat("   >> ");
            drawnow;
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            % Close
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BioFace3DUIFigure = uifigure('Visible', 'off');
            app.BioFace3DUIFigure.Name = 'BioFace3D';
            app.SessionMenu = uimenu(app.BioFace3DUIFigure);
            app.SessionMenu.Text = 'Session';
            app.NewsessionMenu = uimenu(app.SessionMenu);
            app.NewsessionMenu.MenuSelectedFcn = createCallbackFcn(app, @NewsessionMenuSelected, true);
            app.NewsessionMenu.Accelerator = 'N';
            app.NewsessionMenu.Text = 'New session';
            app.SettingsMenu = uimenu(app.SessionMenu);
            app.SettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SettingsMenuSelected, true);
            app.SettingsMenu.Text = 'Settings';
            app.ExitMenu = uimenu(app.SessionMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.Toolbar = uitoolbar(app.BioFace3DUIFigure);
            app.Label = uilabel(app.BioFace3DUIFigure);
            app.Label.Text = '-----------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------';
            app.Tree = uitree(app.BioFace3DUIFigure, 'checkbox');
            app.PathNode = uitreenode(app.Tree);
            app.PathNode.Text = 'Path';
            app.InputfilesLabel = uilabel(app.BioFace3DUIFigure);
            app.InputfilesLabel.Text = 'Input files';
            app.ChangefolderButton = uibutton(app.BioFace3DUIFigure, 'push');
            app.ChangefolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChangefolderButtonPushed, true);
            app.ChangefolderButton.Text = 'Change folder';
            app.Label_2 = uilabel(app.BioFace3DUIFigure);
            app.Label_2.Text = '-----------------------------------------------------------------------------------------------------------------';
            app.filesNumberLabel = uilabel(app.BioFace3DUIFigure);
            app.filesNumberLabel.Text = '0 of 245 files to landmark';
            app.LandmarkingButton = uibutton(app.BioFace3DUIFigure, 'push');
            app.LandmarkingButton.ButtonPushedFcn = createCallbackFcn(app, @LandmarkingButtonPushed, true);
            app.LandmarkingButton.Text = 'Landmarking';
            app.SettingsButton = uibutton(app.BioFace3DUIFigure, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.SettingsButton.Text = 'Settings';
            app.SelectmodelDropDownLabel = uilabel(app.BioFace3DUIFigure);
            app.SelectmodelDropDownLabel.Text = 'Select model';
            app.SelectmodelDropDown = uidropdown(app.BioFace3DUIFigure);
            app.ModelLabel = uilabel(app.BioFace3DUIFigure);
            app.ModelLabel.Text = 'Model';
            app.ParametersLabel = uilabel(app.BioFace3DUIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.MaxRansacErrorSpinnerLabel = uilabel(app.BioFace3DUIFigure);
            app.MaxRansacErrorSpinnerLabel.Text = 'Max. Ransac Error';
            app.MaxRansacErrorSpinner = uispinner(app.BioFace3DUIFigure);
            app.MaxRansacErrorSpinner.Limits = [0 Inf];
            app.MaxRansacErrorSpinner.Value = 5;
            app.MeanPredictionsSpinnerLabel = uilabel(app.BioFace3DUIFigure);
            app.MeanPredictionsSpinnerLabel.Text = 'Mean Predictions';
            app.MeanPredictionsSpinner = uispinner(app.BioFace3DUIFigure);
            app.MeanPredictionsSpinner.Limits = [1 Inf];
            app.MeanPredictionsSpinner.Value = 10;
            app.MaxTriesSpinnerLabel = uilabel(app.BioFace3DUIFigure);
            app.MaxTriesSpinnerLabel.Text = 'Max. Tries';
            app.MaxTriesSpinner = uispinner(app.BioFace3DUIFigure);
            app.MaxTriesSpinner.Limits = [1 Inf];
            app.MaxTriesSpinner.Value = 3;
            app.FormatButtonGroup = uibuttongroup(app.BioFace3DUIFigure);
            app.txtButton = uiradiobutton(app.FormatButtonGroup);
            app.txtButton.Text = 'txt';
            app.txtButton.Value = true;
            app.landmarkAsciiButton = uiradiobutton(app.FormatButtonGroup);
            app.landmarkAsciiButton.Text = 'landmarkAscii';
            app.fcsvButton = uiradiobutton(app.FormatButtonGroup);
            app.fcsvButton.Text = 'fcsv';
            app.allButton = uiradiobutton(app.FormatButtonGroup);
            app.allButton.Text = 'all';
            app.OutputformatLabel = uilabel(app.BioFace3DUIFigure);
            app.OutputformatLabel.Text = 'Output format';
            app.ExcelmetadataLabel = uilabel(app.BioFace3DUIFigure);
            app.ExcelmetadataLabel.Text = 'Excel metadata';
            app.MetadataButtonGroup = uibuttongroup(app.BioFace3DUIFigure);
            app.YesButton = uiradiobutton(app.MetadataButtonGroup);
            app.YesButton.Text = 'Yes';
            app.YesButton.Value = true;
            app.NoButton = uiradiobutton(app.MetadataButtonGroup);
            app.NoButton.Text = 'No';
            app.TreePredictions = uitree(app.BioFace3DUIFigure);
            app.TreePredictions.Enable = 'off';
            app.TreePredictions.DoubleClickedFcn = createCallbackFcn(app, @TreePredictionsDoubleClicked, true);
            app.PredictionsNode = uitreenode(app.TreePredictions);
            app.PredictionsNode.Text = 'Predictions';
            app.TextArea = uitextarea(app.BioFace3DUIFigure);
            app.TextArea.Editable = 'off';
            app.StatusLabel = uilabel(app.BioFace3DUIFigure);
            app.StatusLabel.Text = 'Status';
            app.Label_3 = uilabel(app.BioFace3DUIFigure);
            app.Label_3.Text = '-----------------------------------------------------------------------------------------------------------------';
            app.Label_4 = uilabel(app.BioFace3DUIFigure);
            app.Label_4.Text = '-----------------------------------------------------------------------------------------------------------------';
            app.Label_5 = uilabel(app.BioFace3DUIFigure);
            app.Label_5.Text = '-----------------------------------------------------------------------------------------------------------------';
            app.PredictionsLabel = uilabel(app.BioFace3DUIFigure);
            app.PredictionsLabel.Text = 'Predictions';
            app.SessionNameLabel = uilabel(app.BioFace3DUIFigure);
            app.SessionNameLabel.Text = 'SESSION NAME';
            app.dataSessionLabel = uilabel(app.BioFace3DUIFigure);
            app.dataSessionLabel.Text = '3D Facial Landmarking';
            app.PanelLoading = uipanel(app.BioFace3DUIFigure);
            app.PanelLoading.Visible = 'off';
            app.Image = uiimage(app.PanelLoading);
            app.FileListLabel = uilabel(app.PanelLoading);
            app.FileListLabel.Text = 'Loading landmarking';
            app.ImageLogo = uiimage(app.PanelLoading);
            app.TimeLabel = uilabel(app.PanelLoading);
            app.TimeLabel.Text = 'Calculating remaining time...';
            app.StopButton = uibutton(app.PanelLoading, 'state');
            app.StopButton.Text = 'Stop';
            app.LandmarkingfilesLabel = uilabel(app.PanelLoading);
            app.LandmarkingfilesLabel.Text = 'Landmarking files';
            app.PanelLoadingPLY = uipanel(app.BioFace3DUIFigure);
            app.PanelLoadingPLY.Visible = 'off';
            app.Image_2 = uiimage(app.PanelLoadingPLY);
            app.DisplayingmeshinanewwindowLabel = uilabel(app.PanelLoadingPLY);
            app.DisplayingmeshinanewwindowLabel.Text = 'Displaying mesh in a new window';
            app.ImageLogo_2 = uiimage(app.PanelLoadingPLY);
            % Show the figure after all components are created
            app.BioFace3DUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Landmarking(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BioFace3DUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.BioFace3DUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BioFace3DUIFigure)
        end
    end
end